classdef ROV_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ROVSimulatorUIFigure            matlab.ui.Figure
        FooterLabel_Right               matlab.ui.control.Label
        FooterLabel_Left                matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        SimulationOptionsTab            matlab.ui.container.Tab
        old_version                     matlab.ui.control.Button
        Panel_9                         matlab.ui.container.Panel
        WhenchangingthisparameterremembertoopenanewsimulationfileLabel  matlab.ui.control.Label
        EnablevisualizationCheckBox     matlab.ui.control.CheckBox
        SimulationpropertiesLabel       matlab.ui.control.Label
        LoadingpleasewaitLabel          matlab.ui.control.Label
        Open_controller                 matlab.ui.control.Button
        Open_ref                        matlab.ui.control.Button
        SaveInitialConditions           matlab.ui.control.Button
        OpenSimulationButton            matlab.ui.control.Button
        UITable_InitEta                 matlab.ui.control.Table
        UITable_InitNu                  matlab.ui.control.Table
        Panel_7                         matlab.ui.container.Panel
        Panel_8                         matlab.ui.container.Panel
        ManagesubsystemsLabel           matlab.ui.control.Label
        ParametersTab                   matlab.ui.container.Tab
        EnvironmentPanel                matlab.ui.container.Panel
        EnvironmentDeleteBtn            matlab.ui.control.Button
        EnvironmentDropDown             matlab.ui.control.DropDown
        EnvironmentDropDownLabel        matlab.ui.control.Label
        Delete_Environment              matlab.ui.container.Panel
        NoButton_DeleteEnvironment      matlab.ui.control.Button
        YesButton_DeleteEnvironment     matlab.ui.control.Button
        Text_DeleteEnvironment          matlab.ui.control.Label
        SaveBeforeChange_Environment    matlab.ui.container.Panel
        NoButton_SaveEnvironment        matlab.ui.control.Button
        YesButton_SaveEnvironment       matlab.ui.control.Button
        SaveBeforeText_Environment      matlab.ui.control.Label
        NewEnvironmentPanel             matlab.ui.container.Panel
        AddButton_NewEnv                matlab.ui.control.Button
        NewEnvironmentName              matlab.ui.control.EditField
        SaveEnvironmentParametersButton  matlab.ui.control.Button
        Delete_Robot                    matlab.ui.container.Panel
        NoButton_DeleteRobot            matlab.ui.control.Button
        YesButton_DeleteRobot           matlab.ui.control.Button
        Text_DeleteRobot                matlab.ui.control.Label
        SaveBeforeChange_Robot          matlab.ui.container.Panel
        NoButton_SaveRobot              matlab.ui.control.Button
        YesButton_SaveRobot             matlab.ui.control.Button
        SaveBeforeText_Robot            matlab.ui.control.Label
        NewRobotPanel                   matlab.ui.container.Panel
        AddButton_NewRobot              matlab.ui.control.Button
        NewRobotName                    matlab.ui.control.EditField
        SaveRobotParametersButton       matlab.ui.control.Button
        Panel_10                        matlab.ui.container.Panel
        kgm3Label                       matlab.ui.control.Label
        ms2Label                        matlab.ui.control.Label
        WaterdensityEditField           matlab.ui.control.NumericEditField
        WaterdensityEditFieldLabel      matlab.ui.control.Label
        GravitationalaccelerationgEditField  matlab.ui.control.NumericEditField
        GravitationalaccelerationgEditFieldLabel  matlab.ui.control.Label
        RobotPanel_2                    matlab.ui.container.Panel
        RobotDeleteBtn                  matlab.ui.control.Button
        RobotDropDown                   matlab.ui.control.DropDown
        RobotDropDown_2Label            matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        DimensionsTab                   matlab.ui.container.Tab
        m3Label                         matlab.ui.control.Label
        kgm2Label_3                     matlab.ui.control.Label
        kgm2Label_2                     matlab.ui.control.Label
        mLabel_9                        matlab.ui.control.Label
        mLabel_8                        matlab.ui.control.Label
        AroundzaxisI_zEditField         matlab.ui.control.NumericEditField
        AroundzaxisI_zEditFieldLabel    matlab.ui.control.Label
        AroundyaxisI_yEditField         matlab.ui.control.NumericEditField
        AroundyaxisI_yEditFieldLabel    matlab.ui.control.Label
        AroundxaxisI_xEditField         matlab.ui.control.NumericEditField
        AroundxaxisI_xEditFieldLabel    matlab.ui.control.Label
        MomentofinertiaLabel            matlab.ui.control.Label
        kgm2Label                       matlab.ui.control.Label
        FrontareaLabel                  matlab.ui.control.Label
        VolumeofROVEditField            matlab.ui.control.NumericEditField
        VolumeofROVLabel                matlab.ui.control.Label
        LengthoffrontareaL_lEditField   matlab.ui.control.NumericEditField
        LengthoffrontareaL_lLabel       matlab.ui.control.Label
        WidthoffrontareaL_wEditField    matlab.ui.control.NumericEditField
        WidthoffrontareaL_wEditFieldLabel  matlab.ui.control.Label
        HeightoffrontareaL_hEditField   matlab.ui.control.NumericEditField
        HeightoffrontareaL_hEditFieldLabel  matlab.ui.control.Label
        TotalmassofROVmEditField        matlab.ui.control.NumericEditField
        TotalmassofROVmEditFieldLabel   matlab.ui.control.Label
        mLabel_7                        matlab.ui.control.Label
        kgLabel                         matlab.ui.control.Label
        WeightandbuoyancyTab            matlab.ui.container.Tab
        COCenterofOriginCOMCenterOfMassCOBCenterOfBuoyancyLabel  matlab.ui.control.Label
        mLabel_6                        matlab.ui.control.Label
        mLabel_5                        matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        DistancefromCOtoCOBz_bEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOBz_bEditFieldLabel  matlab.ui.control.Label
        DistancefromCOtoCOBy_bEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOBy_bEditFieldLabel  matlab.ui.control.Label
        DistancefromCOtoCOBx_bEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOBx_bEditFieldLabel  matlab.ui.control.Label
        DistancefromCOtoCOMz_gEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOMz_gEditFieldLabel  matlab.ui.control.Label
        DistancefromCOtoCOMy_gEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOMy_gEditFieldLabel  matlab.ui.control.Label
        DistancefromCOtoCOMx_gEditField  matlab.ui.control.NumericEditField
        DistancefromCOtoCOMx_gEditFieldLabel  matlab.ui.control.Label
        CenterofbuoancyLabel            matlab.ui.control.Label
        CenterofmassLabel               matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        DampingTab                      matlab.ui.container.Tab
        Ns2rad2Label_3                  matlab.ui.control.Label
        Ns2rad2Label_2                  matlab.ui.control.Label
        Ns2rad2Label                    matlab.ui.control.Label
        Ns2m2Label_3                    matlab.ui.control.Label
        Ns2m2Label_2                    matlab.ui.control.Label
        Ns2m2Label                      matlab.ui.control.Label
        NsradLabel_9                    matlab.ui.control.Label
        NsradLabel_8                    matlab.ui.control.Label
        NsradLabel_7                    matlab.ui.control.Label
        NsmLabel_3                      matlab.ui.control.Label
        NsmLabel_2                      matlab.ui.control.Label
        WheninyawN_rrEditField          matlab.ui.control.NumericEditField
        WheninyawN_rrEditFieldLabel     matlab.ui.control.Label
        WheninpitchM_qqEditField        matlab.ui.control.NumericEditField
        WheninpitchM_qqEditFieldLabel   matlab.ui.control.Label
        WheninrollK_ppEditField         matlab.ui.control.NumericEditField
        WheninrollK_ppEditFieldLabel    matlab.ui.control.Label
        WheninheaveZ_wwEditField        matlab.ui.control.NumericEditField
        WheninheaveZ_wwEditFieldLabel   matlab.ui.control.Label
        WheninswayY_vvEditField         matlab.ui.control.NumericEditField
        WheninswayY_vvEditFieldLabel    matlab.ui.control.Label
        WheninsurgeX_uuEditField        matlab.ui.control.NumericEditField
        WheninsurgeX_uuEditFieldLabel   matlab.ui.control.Label
        QuadraticdampingLabel           matlab.ui.control.Label
        WheninyawN_rEditField           matlab.ui.control.NumericEditField
        WheninyawN_rEditFieldLabel      matlab.ui.control.Label
        WheninpitchM_qEditField         matlab.ui.control.NumericEditField
        WheninpitchM_qEditFieldLabel    matlab.ui.control.Label
        WheninrollK_pEditField          matlab.ui.control.NumericEditField
        WheninrollK_pEditFieldLabel     matlab.ui.control.Label
        WheninheaveZ_wEditField         matlab.ui.control.NumericEditField
        WheninheaveZ_wEditFieldLabel    matlab.ui.control.Label
        WheninswayY_vEditField          matlab.ui.control.NumericEditField
        WheninswayY_vEditFieldLabel     matlab.ui.control.Label
        WheninsurgeX_uEditField         matlab.ui.control.NumericEditField
        WheninsurgeX_uEditFieldLabel    matlab.ui.control.Label
        LineardampingLabel              matlab.ui.control.Label
        NsmLabel                        matlab.ui.control.Label
        AddedMassTab                    matlab.ui.container.Tab
        kgLabel_7                       matlab.ui.control.Label
        kgLabel_6                       matlab.ui.control.Label
        kgLabel_5                       matlab.ui.control.Label
        kgLabel_4                       matlab.ui.control.Label
        kgLabel_3                       matlab.ui.control.Label
        m2Label_3                       matlab.ui.control.Label
        m2Label_2                       matlab.ui.control.Label
        m2Label                         matlab.ui.control.Label
        WheninyawN_rdEditField          matlab.ui.control.NumericEditField
        WheninyawN_rdEditFieldLabel     matlab.ui.control.Label
        WheninpitchM_qdEditField        matlab.ui.control.NumericEditField
        WheninpitchM_qdEditFieldLabel   matlab.ui.control.Label
        WheninrollK_pdEditField         matlab.ui.control.NumericEditField
        WheninrollK_pdEditFieldLabel    matlab.ui.control.Label
        WheninheaveZ_wdEditField        matlab.ui.control.NumericEditField
        WheninheaveZ_wdEditFieldLabel   matlab.ui.control.Label
        WheninswayY_vdEditField         matlab.ui.control.NumericEditField
        WheninswayY_vdEditFieldLabel    matlab.ui.control.Label
        WheninsurgeX_udEditField        matlab.ui.control.NumericEditField
        WheninsurgeX_udEditFieldLabel   matlab.ui.control.Label
        AddedmassLabel                  matlab.ui.control.Label
        kgLabel_2                       matlab.ui.control.Label
        TopperspectiveA_TEditField      matlab.ui.control.NumericEditField
        TopperspectiveA_TEditFieldLabel  matlab.ui.control.Label
        SideperspectiveA_SEditField     matlab.ui.control.NumericEditField
        SideperspectiveA_SEditFieldLabel  matlab.ui.control.Label
        FrontperspectiveA_FEditField    matlab.ui.control.NumericEditField
        FrontperspectiveA_FEditFieldLabel  matlab.ui.control.Label
        ProjectedareaLabel              matlab.ui.control.Label
        UpdatedAddedMass                matlab.ui.control.Button
        ThrustersTab                    matlab.ui.container.Tab
        ThrusterconfigurationsLabel     matlab.ui.control.Label
        ImageLight                      matlab.ui.control.Image
        ResetTAMButton                  matlab.ui.control.Button
        ThrusterallocationmatrixTAMLabel  matlab.ui.control.Label
        NoofthrustersSpinner            matlab.ui.control.Spinner
        NoofthrustersLabel              matlab.ui.control.Label
        SaveTAMButton                   matlab.ui.control.Button
        YawLabel                        matlab.ui.control.Label
        PitchLabel                      matlab.ui.control.Label
        RollLabel                       matlab.ui.control.Label
        HeaveLabel                      matlab.ui.control.Label
        SwayLabel                       matlab.ui.control.Label
        SurgeLabel                      matlab.ui.control.Label
        T8Label                         matlab.ui.control.Label
        T7Label                         matlab.ui.control.Label
        T6Label                         matlab.ui.control.Label
        T5Label                         matlab.ui.control.Label
        T4Label                         matlab.ui.control.Label
        T3Label                         matlab.ui.control.Label
        T2Label                         matlab.ui.control.Label
        T1Label                         matlab.ui.control.Label
        T8yaw                           matlab.ui.control.NumericEditField
        T7yaw                           matlab.ui.control.NumericEditField
        T6yaw                           matlab.ui.control.NumericEditField
        T5yaw                           matlab.ui.control.NumericEditField
        T4yaw                           matlab.ui.control.NumericEditField
        T3yaw                           matlab.ui.control.NumericEditField
        T2yaw                           matlab.ui.control.NumericEditField
        T1yaw                           matlab.ui.control.NumericEditField
        T8pitch                         matlab.ui.control.NumericEditField
        T7pitch                         matlab.ui.control.NumericEditField
        T6pitch                         matlab.ui.control.NumericEditField
        T5pitch                         matlab.ui.control.NumericEditField
        T4pitch                         matlab.ui.control.NumericEditField
        T3pitch                         matlab.ui.control.NumericEditField
        T2pitch                         matlab.ui.control.NumericEditField
        T1pitch                         matlab.ui.control.NumericEditField
        T8roll                          matlab.ui.control.NumericEditField
        T7roll                          matlab.ui.control.NumericEditField
        T6roll                          matlab.ui.control.NumericEditField
        T5roll                          matlab.ui.control.NumericEditField
        T4roll                          matlab.ui.control.NumericEditField
        T3roll                          matlab.ui.control.NumericEditField
        T2roll                          matlab.ui.control.NumericEditField
        T1roll                          matlab.ui.control.NumericEditField
        T8heave                         matlab.ui.control.NumericEditField
        T7heave                         matlab.ui.control.NumericEditField
        T6heave                         matlab.ui.control.NumericEditField
        T5heave                         matlab.ui.control.NumericEditField
        T4heave                         matlab.ui.control.NumericEditField
        T3heave                         matlab.ui.control.NumericEditField
        T2heave                         matlab.ui.control.NumericEditField
        T1heave                         matlab.ui.control.NumericEditField
        T8sway                          matlab.ui.control.NumericEditField
        T7sway                          matlab.ui.control.NumericEditField
        T6sway                          matlab.ui.control.NumericEditField
        T5sway                          matlab.ui.control.NumericEditField
        T4sway                          matlab.ui.control.NumericEditField
        T3sway                          matlab.ui.control.NumericEditField
        T2sway                          matlab.ui.control.NumericEditField
        T1sway                          matlab.ui.control.NumericEditField
        T8surge                         matlab.ui.control.NumericEditField
        T7surge                         matlab.ui.control.NumericEditField
        T6surge                         matlab.ui.control.NumericEditField
        T5surge                         matlab.ui.control.NumericEditField
        T4surge                         matlab.ui.control.NumericEditField
        T3surge                         matlab.ui.control.NumericEditField
        T2surge                         matlab.ui.control.NumericEditField
        T1surge                         matlab.ui.control.NumericEditField
        Panel_5                         matlab.ui.container.Panel
        ThrustersaturationNLabel        matlab.ui.control.Label
        SaturateamountofforceforeachthrusterLabel  matlab.ui.control.Label
        LowerlimitEditField             matlab.ui.control.NumericEditField
        LowerlimitLabel                 matlab.ui.control.Label
        UpperlimitEditField             matlab.ui.control.NumericEditField
        UpperlimitEditFieldLabel        matlab.ui.control.Label
        SavethrusterlimitsButton        matlab.ui.control.Button
        Panel_6                         matlab.ui.container.Panel
        ButtonGroup                     matlab.ui.container.ButtonGroup
        BlueROV2HeavyCheckBox           matlab.ui.control.CheckBox
        BlueROV2CheckBox                matlab.ui.control.CheckBox
        ImageHeavy                      matlab.ui.control.Image
        ExternalforcesTab               matlab.ui.container.Tab
        Label_6                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        ManPsiedit                      matlab.ui.control.NumericEditField
        UpdateexternalforcesButton      matlab.ui.control.Button
        Label_3                         matlab.ui.control.Label
        ManZedit                        matlab.ui.control.NumericEditField
        ZEditFieldLabel                 matlab.ui.control.Label
        ManThetaedit                    matlab.ui.control.NumericEditField
        Label_5                         matlab.ui.control.Label
        ManYedit                        matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        ManPhiedit                      matlab.ui.control.NumericEditField
        Label_4                         matlab.ui.control.Label
        ManXedit                        matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        ManualforcesCheckBox            matlab.ui.control.CheckBox
        TetherCheckBox                  matlab.ui.control.CheckBox
        OceancurrentsCheckBox           matlab.ui.control.CheckBox
        Panel                           matlab.ui.container.Panel
        Diameterd_tetEditField          matlab.ui.control.NumericEditField
        Diameterd_tetEditFieldLabel     matlab.ui.control.Label
        EastEEditFieldLabel_11          matlab.ui.control.Label
        InternaldampingC_tetEditField   matlab.ui.control.NumericEditField
        InternaldampingC_tetEditFieldLabel  matlab.ui.control.Label
        EastEEditFieldLabel_10          matlab.ui.control.Label
        TangentieldragCt_tetEditField   matlab.ui.control.NumericEditField
        TangentieldragCt_tetEditFieldLabel  matlab.ui.control.Label
        EastEEditFieldLabel_9           matlab.ui.control.Label
        LengthL_tetEditField            matlab.ui.control.NumericEditField
        LengthL_tetEditFieldLabel       matlab.ui.control.Label
        EastEEditFieldLabel_8           matlab.ui.control.Label
        YoungsmodulusE_tetEditField     matlab.ui.control.NumericEditField
        YoungsmodulusE_tetEditFieldLabel  matlab.ui.control.Label
        EastEEditFieldLabel_7           matlab.ui.control.Label
        NormaldragCn_tetEditField       matlab.ui.control.NumericEditField
        NormaldragCn_tetEditFieldLabel  matlab.ui.control.Label
        EastEEditFieldLabel_6           matlab.ui.control.Label
        EastEEditFieldLabel_5           matlab.ui.control.Label
        Massm_tetEditField              matlab.ui.control.NumericEditField
        Massm_tetEditFieldLabel         matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        EastEEditFieldLabel_17          matlab.ui.control.Label
        EastEEditFieldLabel_16          matlab.ui.control.Label
        EastEEditFieldLabel_15          matlab.ui.control.Label
        EastEEditFieldLabel_14          matlab.ui.control.Label
        EastEEditFieldLabel_13          matlab.ui.control.Label
        EastEEditFieldLabel_12          matlab.ui.control.Label
        Panel_4                         matlab.ui.container.Panel
        EastEEditFieldLabel_3           matlab.ui.control.Label
        EastEEditFieldLabel_2           matlab.ui.control.Label
        NorthNEditField                 matlab.ui.control.NumericEditField
        NorthNEditFieldLabel            matlab.ui.control.Label
        EastEEditField                  matlab.ui.control.NumericEditField
        EastEEditFieldLabel             matlab.ui.control.Label
        SensorsTab                      matlab.ui.container.Tab
        IdealstatesButton               matlab.ui.control.Button
        SamplingratemustbelargerthanzeroLabel  matlab.ui.control.Label
        EditthedifferentsensorsLabel    matlab.ui.control.Label
        ChosewhichsensortouseforeachvelocitymeasurementLabel  matlab.ui.control.Label
        SensorparametersLabel_4         matlab.ui.control.Label
        State_velocities_tab            matlab.ui.container.Panel
        NLabel_14                       matlab.ui.control.Label
        r_StateDropDown                 matlab.ui.control.DropDown
        NLabel_13                       matlab.ui.control.Label
        q_StateDropDown                 matlab.ui.control.DropDown
        NLabel_12                       matlab.ui.control.Label
        p_StateDropDown                 matlab.ui.control.DropDown
        NLabel_11                       matlab.ui.control.Label
        w_StateDropDown                 matlab.ui.control.DropDown
        NLabel_10                       matlab.ui.control.Label
        v_StateDropDown                 matlab.ui.control.DropDown
        u_StateDropDown                 matlab.ui.control.DropDown
        NLabel_9                        matlab.ui.control.Label
        ChosewhichsensortouseforeachpositionmeasurementLabel  matlab.ui.control.Label
        SensorparametersLabel_3         matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        AddnewsensorButton              matlab.ui.control.Button
        UITable_sensor                  matlab.ui.control.Table
        SensorparametersLabel           matlab.ui.control.Label
        UpdatesensorsButton             matlab.ui.control.Button
        State_positions_tab             matlab.ui.container.Panel
        NLabel_8                        matlab.ui.control.Label
        psi_StateDropDown               matlab.ui.control.DropDown
        NLabel_7                        matlab.ui.control.Label
        theta_StateDropDown             matlab.ui.control.DropDown
        NLabel_6                        matlab.ui.control.Label
        phi_StateDropDown               matlab.ui.control.DropDown
        NLabel_5                        matlab.ui.control.Label
        D_StateDropDown                 matlab.ui.control.DropDown
        NLabel_4                        matlab.ui.control.Label
        E_StateDropDown                 matlab.ui.control.DropDown
        N_StateDropDown                 matlab.ui.control.DropDown
        NLabel_3                        matlab.ui.control.Label
        DocumentationTab                matlab.ui.container.Tab
        Image2                          matlab.ui.control.Image
        BIBTEXLabel                     matlab.ui.control.Label
        HarvardLabel                    matlab.ui.control.Label
        TextArea_3                      matlab.ui.control.TextArea
        TextArea_2                      matlab.ui.control.TextArea
        TextArea                        matlab.ui.control.TextArea
        APALabel                        matlab.ui.control.Label
        ToreferencethissoftwareusethefollowingcitationsLabel  matlab.ui.control.Label
        CitationLabel                   matlab.ui.control.Label
        BlueROV2BlueRoboticscomLabel    matlab.ui.control.Label
        AfifKusumaMichaelDziedzicPhotoholgicunsplashcomLabel  matlab.ui.control.Label
        ImagepropertiesLabel            matlab.ui.control.Label
        FolderButton                    matlab.ui.control.Button
        CreatedbyLabel                  matlab.ui.control.Label
        PDFButton                       matlab.ui.control.Button
        CreatedbyFredrikFoghSrensenLabel  matlab.ui.control.Label
    end

    
   properties (Access = private)
        RobotParamsChanged % Description
        RobotChangedName % Description
        EnvironmentParamChanged % Description
        EnvironmentChangedName % Description
    end

    methods (Access = private)
        %% Robots
        function updateRobotTable(app)
            robot = app.RobotDropDown.Value;
            if(robot~="Add New")
                params = LoadRobotParameters(robot);
                if(length(struct2cell(params))>1)

                    app.RobotParamsChanged = false;
                    app.RobotChangedName = "";
                    app.SaveRobotParametersButton.Enable = 'off';
                    app.NewRobotPanel.Visible = 'off';
                    app.NewRobotPanel.Enable = 'off';

                    % edit fields instead
                    app.TotalmassofROVmEditField.Value = params.m;
                    app.VolumeofROVEditField.Value = params.Volume;
                    app.HeightoffrontareaL_hEditField.Value = params.L(1);   
                    app.WidthoffrontareaL_wEditField.Value = params.L(2);
                    app.LengthoffrontareaL_lEditField.Value = params.L(3);
                    app.AroundxaxisI_xEditField.Value = params.MOI(1);
                    app.AroundyaxisI_yEditField.Value = params.MOI(2);
                    app.AroundzaxisI_zEditField.Value = params.MOI(3);

                    app.DistancefromCOtoCOMx_gEditField.Value = params.x_g;
                    app.DistancefromCOtoCOMy_gEditField.Value = params.y_g;
                    app.DistancefromCOtoCOMz_gEditField.Value = params.z_g;
                    app.DistancefromCOtoCOBx_bEditField.Value = params.x_b;
                    app.DistancefromCOtoCOBy_bEditField.Value = params.y_b;
                    app.DistancefromCOtoCOBz_bEditField.Value = params.z_b;

                    app.WheninsurgeX_uEditField.Value = params.LinDrag(1);
                    app.WheninswayY_vEditField.Value = params.LinDrag(2);
                    app.WheninheaveZ_wEditField.Value = params.LinDrag(3);
                    app.WheninrollK_pEditField.Value = params.LinDrag(4);
                    app.WheninpitchM_qEditField.Value = params.LinDrag(5);
                    app.WheninyawN_rEditField.Value = params.LinDrag(6);
                    app.WheninsurgeX_uuEditField.Value = params.NonLinDrag(1);
                    app.WheninswayY_vvEditField.Value = params.NonLinDrag(2);
                    app.WheninheaveZ_wwEditField.Value = params.NonLinDrag(3);
                    app.WheninrollK_ppEditField.Value = params.NonLinDrag(4);
                    app.WheninpitchM_qqEditField.Value = params.NonLinDrag(5);
                    app.WheninyawN_rrEditField.Value = params.NonLinDrag(6);

                    app.FrontperspectiveA_FEditField.Value = params.A(1);
                    app.SideperspectiveA_SEditField.Value = params.A(2);
                    app.TopperspectiveA_TEditField.Value = params.A(3);
                    app.WheninsurgeX_udEditField.Value = params.AddedMass(1);
                    app.WheninswayY_vdEditField.Value = params.AddedMass(2);
                    app.WheninheaveZ_wdEditField.Value = params.AddedMass(3);
                    app.WheninrollK_pdEditField.Value = params.AddedMass(4);
                    app.WheninpitchM_qdEditField.Value = params.AddedMass(5);
                    app.WheninyawN_rdEditField.Value = params.AddedMass(6);
                end
            else
                app.NewRobotPanel.Visible = 'on';
                app.NewRobotPanel.Enable = 'on';
            end
        end
        
        function saveRobotTable(app,robotName)           
            param.m = app.TotalmassofROVmEditField.Value;%table2array(retreivedata(1,3));

            param.MOI.x = app.AroundxaxisI_xEditField.Value;%table2array(retreivedata(2,3));
            param.MOI.y = app.AroundyaxisI_yEditField.Value;%table2array(retreivedata(3,3));
            param.MOI.z = app.AroundzaxisI_zEditField.Value;%table2array(retreivedata(4,3));

            param.L.h = app.HeightoffrontareaL_hEditField.Value;%table2array(retreivedata(5,3));
            param.L.w = app.WidthoffrontareaL_wEditField.Value;%table2array(retreivedata(6,3));
            param.L.l = app.LengthoffrontareaL_lEditField.Value;%table2array(retreivedata(7,3));
            
            param.A.F = app.FrontperspectiveA_FEditField.Value;%table2array(retreivedata(8,3));
            param.A.S = app.SideperspectiveA_SEditField.Value;%table2array(retreivedata(9,3));
            param.A.T = app.TopperspectiveA_TEditField.Value;%table2array(retreivedata(10,3));
            
            param.Volume = app.VolumeofROVEditField.Value;%table2array(retreivedata(11,3));
            
            param.x_g = app.DistancefromCOtoCOMx_gEditField.Value;%table2array(retreivedata(12,3));
            param.y_g = app.DistancefromCOtoCOMy_gEditField.Value;%table2array(retreivedata(13,3));
            param.z_g = app.DistancefromCOtoCOMz_gEditField.Value;%table2array(retreivedata(14,3));
            
            param.x_b = app.DistancefromCOtoCOBx_bEditField.Value;%table2array(retreivedata(15,3));
            param.y_b = app.DistancefromCOtoCOBy_bEditField.Value;%table2array(retreivedata(16,3));
            param.z_b = app.DistancefromCOtoCOBz_bEditField.Value;%table2array(retreivedata(17,3));
            
            param.AddedMass.X_ud = app.WheninsurgeX_udEditField.Value;%table2array(retreivedata(18,3));
            param.AddedMass.Y_vd = app.WheninswayY_vdEditField.Value;%table2array(retreivedata(19,3));
            param.AddedMass.Z_wd = app.WheninheaveZ_wdEditField.Value;%table2array(retreivedata(20,3));
            param.AddedMass.K_pd = app.WheninrollK_pdEditField.Value;%table2array(retreivedata(21,3));
            param.AddedMass.M_qd = app.WheninpitchM_qdEditField.Value;%table2array(retreivedata(22,3));
            param.AddedMass.N_rd = app.WheninyawN_rdEditField.Value;%table2array(retreivedata(23,3));
            
            param.Drag.Linear.Xu = app.WheninsurgeX_uEditField.Value;%table2array(retreivedata(24,3));
            param.Drag.Linear.Yv = app.WheninswayY_vEditField.Value;%table2array(retreivedata(25,3));
            param.Drag.Linear.Zw = app.WheninheaveZ_wEditField.Value;%table2array(retreivedata(26,3));
            param.Drag.Linear.Kp = app.WheninrollK_pEditField.Value;%table2array(retreivedata(27,3));
            param.Drag.Linear.Mq = app.WheninpitchM_qEditField.Value;%table2array(retreivedata(28,3));
            param.Drag.Linear.Nr = app.WheninyawN_rEditField.Value;%table2array(retreivedata(29,3));
            
            param.Drag.Nonlinear.Xu = app.WheninsurgeX_uuEditField.Value;%table2array(retreivedata(30,3));
            param.Drag.Nonlinear.Yv = app.WheninswayY_vvEditField.Value;%table2array(retreivedata(31,3));
            param.Drag.Nonlinear.Zw = app.WheninheaveZ_wwEditField.Value;%table2array(retreivedata(32,3));
            param.Drag.Nonlinear.Kp = app.WheninrollK_ppEditField.Value;%table2array(retreivedata(33,3));
            param.Drag.Nonlinear.Mq = app.WheninpitchM_qqEditField.Value;%table2array(retreivedata(34,3));
            param.Drag.Nonlinear.Nr = app.WheninyawN_rrEditField.Value;%table2array(retreivedata(35,3));
            
            param.I_c = diag([param.MOI.x,param.MOI.y,param.MOI.z]);
            
            param.A_p.F = param.L.l*param.L.h;
            param.A_p.S = param.L.w*param.L.h;
            param.A_p.T = param.L.l*param.L.w;

            param.Drag.NonLinVector = [param.Drag.Nonlinear.Xu; param.Drag.Nonlinear.Yv; param.Drag.Nonlinear.Zw; param.Drag.Nonlinear.Kp; param.Drag.Nonlinear.Mq; param.Drag.Nonlinear.Nr];
            param.Drag.LinVector = [param.Drag.Linear.Xu; param.Drag.Linear.Yv; param.Drag.Linear.Zw; param.Drag.Linear.Kp; param.Drag.Linear.Mq; param.Drag.Linear.Nr];

            eval([sprintf('%s',robotName) '= param;']);
            save("Parameters\Robots.mat",robotName,'-append');
            app.RobotParamsChanged = false;
            app.RobotChangedName = "";
        end
        %%
          %% Environments
        function updateEnvironmentTable(app)
            env = app.EnvironmentDropDown.Value;
            if(env~="Add New")
                ENV = LoadEnvironment(env);
                params = struct2cell(ENV);
                if(length(params)>1)
%                     uit = app.UITable_Environment;
%                     name = ["Gravitational acceleration";"Water density"];
%                     symbols = ["g";char(961)];
                     values = [params{1:2}]';
%                     units = ["m/s^2";"kg/m^3"];
%                     vars = {'Description';'Symbol';'Value';'Unit'};
%                     tdata = table(name,symbols,values,units);
%                     tdata.Properties.VariableNames = vars;
%                     uit.Data = tdata;
%                     uit.ColumnName = vars;
%                     uit.ColumnEditable = [false false true false];

                    app.GravitationalaccelerationgEditField.Value = values(1);
                    app.WaterdensityEditField.Value = values(2);

                    app.EnvironmentParamChanged = false;
                    app.EnvironmentChangedName = "";
                    app.SaveEnvironmentParametersButton.Enable = 'off';
%                     app.UITable_Environment.Visible = 'on';
                    app.NewEnvironmentPanel.Visible = 'off';
                    app.NewEnvironmentPanel.Enable = 'off';
                end
            else
%                 app.UITable_Environment.Visible = 'off';
                app.NewEnvironmentPanel.Visible = 'on';
                app.NewEnvironmentPanel.Enable = 'on';
            end
        end
        
        function saveEnvironmentTable(app,envName)
            %retreivedata = get(app.UITable_Environment,'data');
            
            param.g = app.GravitationalaccelerationgEditField.Value;
            param.rho = app.WaterdensityEditField.Value;
            
            eval([sprintf('%s',envName) '= param;']);
            save("Environments.mat",envName,'-append');
            app.EnvironmentParamChanged = false;
            app.EnvironmentChangedName = "";
        end
         %% Sensors
         function updateSensorTable(app)
            % load standard sensors
            sysSEN = load("Dependencies\Workspaces\Sensors.mat");
            % load system parameters
            Sim_param = load("Dependencies\Workspaces\simparam.mat");
            % load user sensors
%             usrSEN = load("Parameters\Sensors.mat"); % for later use
            % create combined array of structs
            array = struct2array(sysSEN);
%            array(:,end+1) = struct2array(usrSEN);  
            uit = app.UITable_sensor;
            %columnformat = {'char','char','char', 'logical'};
            name = [fieldnames(sysSEN)];
%            name = [fieldnames(sysSEN); fieldnames(usrSEN)];
            
            str_states = ["N";"E";"D";"Φ";"θ";"Ψ";"u";"v";"w";"p";"q";"r"];
            for i = 1:length(array)
                 states(i) = strjoin(str_states(array(i).states));
            end

            format longG
            ts = [array.ts]';
            delay = [array.Delay]';
            variance = [array.Variance]';
            drift = [array.Drift]';
            vars = {'Sensor';'State-measurements';'Sampling rate [Hz]';'Delay [s]';'Variance (e-3) [m]/[rad]';'Drift [m/s]/[rad/s]'};
            tdata = table(name,states',ts,delay,variance,drift);
            uit.ColumnEditable = [false false true true true true];
            uit.Data = tdata;
            uit.ColumnName = vars;
        end
        
        function saveSensorTable(app)
            retrievedata = get(app.UITable_sensor,'data');
            
            DVL.ts = table2array(retrievedata(1,3));
            DVL.Drift = table2array(retrievedata(1,6));
            DVL.Delay = table2array(retrievedata(1,4));
            DVL.Variance = table2array(retrievedata(1,5));
            DVL.states = [7 8 9];
            IMU.ts = table2array(retrievedata(2,3));
            IMU.Drift =table2array(retrievedata(2,6));
            IMU.Delay = table2array(retrievedata(2,4));
            IMU.Variance = table2array(retrievedata(2,5));
            IMU.states = [4 5 10 11];
            Laser.ts = table2array(retrievedata(3,3));
            Laser.Drift =table2array(retrievedata(3,6));
            Laser.Delay = table2array(retrievedata(3,4));
            Laser.Variance = table2array(retrievedata(3,5));
            Laser.states = [1 2];
            Magnetometer.ts = table2array(retrievedata(4,3));
            Magnetometer.Drift =table2array(retrievedata(4,6));
            Magnetometer.Delay = table2array(retrievedata(4,4));
            Magnetometer.Variance = table2array(retrievedata(4,5));
            Magnetometer.states = [6 12];
            Pressure.ts = table2array(retrievedata(5,3));
            Pressure.Drift =table2array(retrievedata(5,6));
            Pressure.Delay = table2array(retrievedata(5,4));
            Pressure.Variance = table2array(retrievedata(5,5));
            Pressure.states = 3;
            SBL.ts = table2array(retrievedata(6,3));
            SBL.Drift =table2array(retrievedata(6,6));
            SBL.Delay = table2array(retrievedata(6,4));
            SBL.Variance = table2array(retrievedata(6,5));
            SBL.states = [1 2];
            Sonar.ts = table2array(retrievedata(7,3));
            Sonar.Drift =table2array(retrievedata(7,6));
            Sonar.Delay = table2array(retrievedata(7,4));
            Sonar.Variance = table2array(retrievedata(7,5));
            Sonar.states = [1 2];
            Custom.ts = table2array(retrievedata(8,3));
            Custom.Drift =table2array(retrievedata(8,6));
            Custom.Delay = table2array(retrievedata(8,4));
            Custom.Variance = table2array(retrievedata(8,5));
            Custom.states = [1 2 3 4 5 6 7 8 9 10 11 12];
            
            save("Dependencies\Workspaces\Sensors.mat","DVL","IMU","Laser","Magnetometer","Pressure","SBL","Sonar","Custom",'-append');
            app.EnvironmentParamChanged = false;
            app.EnvironmentChangedName = "";
        end
        %%
        %%
        %% Simulation
        %%
        %%
        function updateInitNuTable(app)
            uit = app.UITable_InitNu;
            Init = load("Parameters\InitCond.mat");
            dof = ["u";"v";"w";"p";"q";"r"];
            values = [Init.nu_IC.u;Init.nu_IC.v;Init.nu_IC.w;Init.nu_IC.p;Init.nu_IC.q;Init.nu_IC.r];
            units = ["m/s";"m/s";"m/s";"rad/s";"rad/s";"rad/s"];
            vars = {'DOF';'Value';'Unit'};
            tdata = table(dof,values,units);
            tdata.Properties.VariableNames = vars;
            uit.Data = tdata;
            uit.ColumnName = vars;
            uit.ColumnEditable = [false true false];
        end
        
        function updateInitEtaTable(app)
            uit = app.UITable_InitEta;
            Init = load("Parameters\InitCond.mat");
            dof = ["N";"E";"D";char(966);char(952);char(936)];
            values = [Init.eta_IC.N;Init.eta_IC.E;Init.eta_IC.D;Init.eta_IC.phi;Init.eta_IC.theta;Init.eta_IC.psi];
            units = ["m";"m";"m";"rad";"rad";"rad"];
            vars = {'DOF';'Value';'Unit'};
            tdata = table(dof,values,units);
            tdata.Properties.VariableNames = vars;
            uit.Data = tdata;
            uit.ColumnName = vars;
            uit.ColumnEditable = [false true false];
        end
        
        function saveInitTable(app)
            retreive_nu = get(app.UITable_InitNu,'data');
            retreive_eta = get(app.UITable_InitEta,'data');
            nu_IC.u = table2array(retreive_nu(1,2));
            nu_IC.v = table2array(retreive_nu(2,2));
            nu_IC.w = table2array(retreive_nu(3,2));
            nu_IC.p = table2array(retreive_nu(4,2));
            nu_IC.q = table2array(retreive_nu(5,2));
            nu_IC.r = table2array(retreive_nu(6,2));
            
            eta_IC.N = table2array(retreive_eta(1,2));
            eta_IC.E = table2array(retreive_eta(2,2));
            eta_IC.D = table2array(retreive_eta(3,2));
            eta_IC.phi = table2array(retreive_eta(4,2));
            eta_IC.theta = table2array(retreive_eta(5,2));
            eta_IC.psi = table2array(retreive_eta(6,2));
            
            save("Parameters\InitCond.mat","nu_IC","eta_IC",'-append');
        end

        function updateThrusterLimits(app)
            Init = load("Parameters\thrusterLimit.mat");
            app.LowerlimitEditField.Value = Init.thrustLowerLimit;
            app.UpperlimitEditField.Value = Init.thrustUpperLimit;
        end

        function saveThrusterLimits(app)
            retrieveUpperlimit = app.UpperlimitEditField.Value;
            retrieveLowerlimit = app.LowerlimitEditField.Value;
            thrustUpperLimit = retrieveUpperlimit;
            thrustLowerLimit = retrieveLowerlimit;
            
            save("Parameters\thrusterLimit.mat","thrustUpperLimit","thrustLowerLimit",'-append');
        end
        
        function updateTAM(app)
            Init = load("Parameters\TAM.mat");
            T = Init.T;
            app.T1surge.Value = T(1,1);app.T1sway.Value = T(2,1);app.T1heave.Value = T(3,1);app.T1roll.Value = T(4,1);app.T1pitch.Value = T(5,1);app.T1yaw.Value = T(6,1);
            app.T2surge.Value = T(1,2);app.T2sway.Value = T(2,2);app.T2heave.Value = T(3,2);app.T2roll.Value = T(4,2);app.T2pitch.Value = T(5,2);app.T2yaw.Value = T(6,2);
            app.T3surge.Value = T(1,3);app.T3sway.Value = T(2,3);app.T3heave.Value = T(3,3);app.T3roll.Value = T(4,3);app.T3pitch.Value = T(5,3);app.T3yaw.Value = T(6,3);
            app.T4surge.Value = T(1,4);app.T4sway.Value = T(2,4);app.T4heave.Value = T(3,4);app.T4roll.Value = T(4,4);app.T4pitch.Value = T(5,4);app.T4yaw.Value = T(6,4);
            app.T5surge.Value = T(1,5);app.T5sway.Value = T(2,5);app.T5heave.Value = T(3,5);app.T5roll.Value = T(4,5);app.T5pitch.Value = T(5,5);app.T5yaw.Value = T(6,5);
            app.T6surge.Value = T(1,6);app.T6sway.Value = T(2,6);app.T6heave.Value = T(3,6);app.T6roll.Value = T(4,6);app.T6pitch.Value = T(5,6);app.T6yaw.Value = T(6,6);
            app.T7surge.Value = T(1,7);app.T7sway.Value = T(2,7);app.T7heave.Value = T(3,7);app.T7roll.Value = T(4,7);app.T7pitch.Value = T(5,7);app.T7yaw.Value = T(6,7);
            app.T8surge.Value = T(1,8);app.T8sway.Value = T(2,8);app.T8heave.Value = T(3,8);app.T8roll.Value = T(4,8);app.T8pitch.Value = T(5,8);app.T8yaw.Value = T(6,8);
        end

        function saveTAM(app)
            T = [
                app.T1surge.Value app.T2surge.Value app.T3surge.Value app.T4surge.Value app.T5surge.Value app.T6surge.Value app.T7surge.Value app.T8surge.Value;
                app.T1sway.Value app.T2sway.Value app.T3sway.Value app.T4sway.Value app.T5sway.Value app.T6sway.Value app.T7sway.Value app.T8sway.Value;
                app.T1heave.Value app.T2heave.Value app.T3heave.Value app.T4heave.Value app.T5heave.Value app.T6heave.Value app.T7heave.Value app.T8heave.Value;
                app.T1roll.Value app.T2roll.Value app.T3roll.Value app.T4roll.Value app.T5roll.Value app.T6roll.Value app.T7roll.Value app.T8roll.Value;
                app.T1pitch.Value app.T2pitch.Value app.T3pitch.Value app.T4pitch.Value app.T5pitch.Value app.T6pitch.Value app.T7pitch.Value app.T8pitch.Value;
                app.T1yaw.Value app.T2yaw.Value app.T3yaw.Value app.T4yaw.Value app.T5yaw.Value app.T6yaw.Value app.T7yaw.Value app.T8yaw.Value
                ];
            if(app.NoofthrustersSpinner.Value < 8)
                T(:,app.NoofthrustersSpinner.Value+1:8) = 0;
            end
            T_inv = pinv(round(T,4));
            save("Parameters\TAM.mat","T","T_inv",'-append');
            updateSIMPARAM(app);
        end

        function updateEXT(app)
            Init = load("Parameters\External_forces.mat");
            app.InternaldampingC_tetEditField.Value = Init.EXT.Tether.C;
            app.NormaldragCn_tetEditField.Value = Init.EXT.Tether.Cn;
            app.TangentieldragCt_tetEditField.Value = Init.EXT.Tether.Ct;
            app.YoungsmodulusE_tetEditField.Value = Init.EXT.Tether.E;
            app.LengthL_tetEditField.Value = Init.EXT.Tether.L;
            app.Diameterd_tetEditField.Value = Init.EXT.Tether.d;
            app.Massm_tetEditField.Value = Init.EXT.Tether.M;
            app.NorthNEditField.Value = Init.EXT.Currents.N;
            app.EastEEditField.Value = Init.EXT.Currents.E;
            app.ManXedit.Value = Init.EXT.Man.X;
            app.ManYedit.Value = Init.EXT.Man.Y;
            app.ManZedit.Value = Init.EXT.Man.Z;
            app.ManPhiedit.Value = Init.EXT.Man.phi;
            app.ManThetaedit.Value = Init.EXT.Man.theta;
            app.ManPsiedit.Value = Init.EXT.Man.psi;
            
        end

        function saveEXT(app)
            EXT.Tether.C = app.InternaldampingC_tetEditField.Value;
            EXT.Tether.Cn = app.NormaldragCn_tetEditField.Value;
            EXT.Tether.Ct = app.TangentieldragCt_tetEditField.Value;
            EXT.Tether.E = app.YoungsmodulusE_tetEditField.Value;
            EXT.Tether.L = app.LengthL_tetEditField.Value;
            EXT.Tether.M = app.Massm_tetEditField.Value;
            EXT.Tether.d = app.Diameterd_tetEditField.Value;
            EXT.Currents.N = app.NorthNEditField.Value;
            EXT.Currents.E = app.EastEEditField.Value;
            EXT.Man.X = app.ManXedit.Value;
            EXT.Man.Y = app.ManYedit.Value;
            EXT.Man.Z = app.ManZedit.Value;
            EXT.Man.phi = app.ManPhiedit.Value;
            EXT.Man.theta = app.ManThetaedit.Value;
            EXT.Man.psi = app.ManPsiedit.Value;
            save("Parameters\External_forces.mat","EXT",'-append');
        end

        function updateSIMPARAM(app) % update simulation parameters
            % external forces
            Sim_param.extManUse = app.ManualforcesCheckBox.Value;
            Sim_param.extTetUse = app.TetherCheckBox.Value;
            Sim_param.extCurrUse = app.OceancurrentsCheckBox.Value;
            % Thrusters
            Sim_param.Tsize = app.NoofthrustersSpinner.Value;
            % VR
            Sim_param.UseVR = app.EnablevisualizationCheckBox.Value;
            
            % States
            Sim_param.States.N = app.N_StateDropDown.Value;
            Sim_param.States.E = app.E_StateDropDown.Value;            
            Sim_param.States.D = app.D_StateDropDown.Value;
            Sim_param.States.phi = app.phi_StateDropDown.Value;
            Sim_param.States.theta = app.theta_StateDropDown.Value;
            Sim_param.States.psi = app.psi_StateDropDown.Value;
            Sim_param.States.u = app.u_StateDropDown.Value;
            Sim_param.States.v = app.v_StateDropDown.Value;
            Sim_param.States.w = app.w_StateDropDown.Value;
            Sim_param.States.p = app.p_StateDropDown.Value;
            Sim_param.States.q = app.q_StateDropDown.Value;
            Sim_param.States.r = app.r_StateDropDown.Value;

            % Robot choice
            Sim_param.Robot = app.RobotDropDown.Value;

            % Environment choice
            Sim_param.Env = app.EnvironmentDropDown.Value;

            save("Dependencies\Workspaces\simparam.mat","Sim_param",'-append');
        end
        
        
        function loadSIMPARAM(app)
            Sim_param = load("Dependencies\Workspaces\simparam.mat");
            % VR
            value = Sim_param.Sim_param.UseVR;
            app.EnablevisualizationCheckBox.Value = value;
            if value == 1 
                
            else
                
            end
            % external forces
            app.OceancurrentsCheckBox.Value = Sim_param.Sim_param.extCurrUse;
            OceancurrentsCheckBoxValueChanged(app);
            app.TetherCheckBox.Value = Sim_param.Sim_param.extTetUse;
            TetherCheckBoxValueChanged(app);
            app.ManualforcesCheckBox.Value = Sim_param.Sim_param.extManUse;
            ManualforcesCheckBoxValueChanged(app);
            % thrusters
            app.NoofthrustersSpinner.Value = Sim_param.Sim_param.Tsize;
            NoofthrustersSpinnerValueChanged(app);
            
             % States
             app.N_StateDropDown.Value = Sim_param.Sim_param.States.N;
             app.E_StateDropDown.Value = Sim_param.Sim_param.States.E;
             app.D_StateDropDown.Value = Sim_param.Sim_param.States.D;
             app.phi_StateDropDown.Value = Sim_param.Sim_param.States.phi;
             app.theta_StateDropDown.Value = Sim_param.Sim_param.States.theta;
             app.psi_StateDropDown.Value = Sim_param.Sim_param.States.psi;
             app.u_StateDropDown.Value = Sim_param.Sim_param.States.u;
             app.v_StateDropDown.Value = Sim_param.Sim_param.States.v;
             app.w_StateDropDown.Value = Sim_param.Sim_param.States.w;
             app.p_StateDropDown.Value = Sim_param.Sim_param.States.p;
             app.q_StateDropDown.Value = Sim_param.Sim_param.States.q;
             app.r_StateDropDown.Value = Sim_param.Sim_param.States.r;

             % Robot choice
             app.RobotDropDown.Value = Sim_param.Sim_param.Robot;
             updateRobotTable(app);
             % Environment choice
             app.EnvironmentDropDown.Value = Sim_param.Sim_param.Env;
             updateEnvironmentTable(app);
        end
        
        function updateStateSensor(app)
            app.N_StateDropDown.ItemsData = [3 6 7 8 0];
            app.E_StateDropDown.ItemsData = [3 6 7 8 0];
            app.D_StateDropDown.ItemsData = [5 8 0];
            app.phi_StateDropDown.ItemsData = [2 8 0];
            app.theta_StateDropDown.ItemsData = [2 8 0];
            app.psi_StateDropDown.ItemsData = [4 8 0];
            app.u_StateDropDown.ItemsData = [1 8 0];
            app.v_StateDropDown.ItemsData = [1 8 0];
            app.w_StateDropDown.ItemsData = [1 8 0];
            app.p_StateDropDown.ItemsData = [2 8 0];
            app.q_StateDropDown.ItemsData = [2 8 0];
            app.r_StateDropDown.ItemsData = [4 8 0];

            app.N_StateDropDown.Items = ["Laser" "SBL" "Sonar" "Custom" "Ideal"];
            app.E_StateDropDown.Items = ["Laser" "SBL" "Sonar" "Custom" "Ideal"];
            app.D_StateDropDown.Items = ["Pressure" "Custom" "Ideal"];
            app.phi_StateDropDown.Items = ["IMU" "Custom" "Ideal"];
            app.theta_StateDropDown.Items = ["IMU" "Custom" "Ideal"];
            app.psi_StateDropDown.Items = ["Magnetometer" "Custom" "Ideal"];
            app.u_StateDropDown.Items = ["DVL" "Custom" "Ideal"];
            app.v_StateDropDown.Items = ["DVL" "Custom" "Ideal"];
            app.w_StateDropDown.Items = ["DVL" "Custom" "Ideal"];
            app.p_StateDropDown.Items = ["IMU" "Custom" "Ideal"];
            app.q_StateDropDown.Items = ["IMU" "Custom" "Ideal"];
            app.r_StateDropDown.Items = ["Magnetometer" "Custom" "Ideal"];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if(isMATLABReleaseOlderThan("R2024a") == 1)
                app.old_version.Visible = 'on';
            end
            
            % Add all folders and subfolders to path
            addpath(genpath('Dependencies/'));
            addpath(genpath('Documentation/'));
            addpath(genpath('Dynamic_Model/'));
            addpath(genpath('Parameters/'));
            
            app.SaveBeforeChange_Robot.Visible = 'off';
            app.SaveBeforeChange_Robot.Enable = 'off';
            app.SaveBeforeChange_Environment.Visible = 'off';
            app.SaveBeforeChange_Environment.Enable = 'off';
            app.Delete_Robot.Visible = 'off';
            app.Delete_Robot.Enable = 'off';
            app.Delete_Environment.Visible = 'off';
            app.Delete_Environment.Enable = 'off';
            robotNames = who("-file","Parameters\Robots.mat");
            for a = 1:length(robotNames)
                DropDownRobotParams(a) = string(robotNames{a});
            end
            DropDownRobotParams = [DropDownRobotParams "Add New"];
            app.RobotDropDown.Items = DropDownRobotParams;
            app.RobotDropDown.ItemsData = DropDownRobotParams;
            %app.RobotDropDown.Value = string(robotNames{1});
            %updateRobotTable(app);

            envNames = who("-file","Parameters\Environments.mat");
            for a = 1:length(envNames)
                DropDownEnvParams(a) = string(envNames{a});
            end
            DropDownEnvParams = [DropDownEnvParams "Add New"];
            app.EnvironmentDropDown.Items = DropDownEnvParams;
            app.EnvironmentDropDown.ItemsData = DropDownEnvParams;
            %app.EnvironmentDropDown.Value = string(envNames{1});
            %updateEnvironmentTable(app);

            updateInitNuTable(app);
            updateInitEtaTable(app);
            updateThrusterLimits(app);
            updateTAM(app);
            updateEXT(app);
            updateSensorTable(app);

            % update state dropdowns in sensor choice
            updateStateSensor(app)

            % load simulation parameters
            loadSIMPARAM(app);
        end

        % Button pushed function: UpdatedAddedMass
        function UpdatedAddedMassButtonPushed(app, event)
             robot = app.RobotDropDown.Value;
             ROV = LoadRobot(robot);
             env = app.EnvironmentDropDown.Value;
             ENV = LoadEnvironment(env);
             coef = AddedMassCoef(ROV,ENV,1:6);
              if(max(isnan(coef)) == 1)
                 coef = zeros(1,6);
              end
              app.WheninsurgeX_udEditField.Value = coef(1); 
              app.WheninswayY_vdEditField.Value =  coef(2); 
             app.WheninheaveZ_wdEditField.Value =  coef(3); 
             app.WheninrollK_pdEditField.Value = coef(4); 
             app.WheninpitchM_qdEditField.Value = coef(5); 
             app.WheninyawN_rdEditField.Value = coef(6);
              app.SaveRobotParametersButton.Enable = 'on';
        end

        % Button pushed function: SaveRobotParametersButton
        function SaveRobotParametersButtonPushed(app, event)
            saveRobotTable(app,app.RobotDropDown.Value);
            app.SaveRobotParametersButton.Enable = 'off';
        end

        % Button pushed function: RobotDeleteBtn
        function RobotDeleteBtnButtonPushed(app, event)
            app.Delete_Robot.Visible = 'on';
            app.Delete_Robot.Enable = 'on';
        end

        % Button pushed function: YesButton_DeleteRobot
        function YesButton_DeleteRobotPushed(app, event)
            [appPath,~,~] = fileparts(mfilename('fullpath'));
            rmmatvar(appPath + "\Parameters\Robots.mat",app.RobotDropDown.Value);
            robotNames = who("-file", appPath + "\Parameters\Robots.mat");
            for a = 1:length(robotNames)
                DropDownRobotParams(a) = string(robotNames{a});
            end
            DropDownRobotParams = [DropDownRobotParams "Add New"];
            app.RobotDropDown.Items = DropDownRobotParams;
            app.RobotDropDown.ItemsData = DropDownRobotParams;
            app.RobotDropDown.Value = string(robotNames{1});
            updateRobotTable(app);
            app.Delete_Robot.Visible = 'off';
            app.Delete_Robot.Enable = 'off';            
        end

        % Button pushed function: NoButton_DeleteRobot
        function NoButton_DeleteRobotPushed(app, event)
            app.Delete_Robot.Visible = 'off';
            app.Delete_Robot.Enable = 'off'; 
        end

        % Button pushed function: SaveEnvironmentParametersButton
        function SaveEnvironmentParametersButtonPushed(app, event)
            saveEnvironmentTable(app,app.EnvironmentDropDown.Value);
            app.SaveEnvironmentParametersButton.Enable = 'off';
        end

        % Button pushed function: EnvironmentDeleteBtn
        function EnvironmentDeleteBtnButtonPushed(app, event)
            app.Delete_Environment.Visible = 'on';
            app.Delete_Environment.Enable = 'on';
        end

        % Button pushed function: YesButton_DeleteEnvironment
        function YesButton_DeleteEnvironmentPushed(app, event)
            rmmatvar("Parameters\Environments.mat",app.EnvironmentDropDown.Value);
            envNames = who("-file","Parameters\Environments.mat");
            for a = 1:length(envNames)
                DropDownEnvParams(a) = string(envNames{a});
            end
            DropDownEnvParams = [DropDownEnvParams "Add New"];
            app.EnvironmentDropDown.Items = DropDownEnvParams;
            app.EnvironmentDropDown.ItemsData = DropDownEnvParams;
            app.EnvironmentDropDown.Value = string(envNames{1});
            updateEnvironmentTable(app);
            app.Delete_Environment.Visible = 'off';
            app.Delete_Environment.Enable = 'off';
        end

        % Button pushed function: NoButton_DeleteEnvironment
        function NoButton_DeleteEnvironmentPushed(app, event)
            app.Delete_Environment.Visible = 'off';
            app.Delete_Environment.Enable = 'off';
        end

        % Value changed function: RobotDropDown
        function RobotDropDownValueChanged(app, event)
            if(app.RobotParamsChanged)
                app.SaveBeforeChange_Robot.Visible = 'on';
                app.SaveBeforeChange_Robot.Enable = 'on';
            else
                updateRobotTable(app);
                updateSIMPARAM(app);
            end
        end

        % Value changed function: EnvironmentDropDown
        function EnvironmentDropDownValueChanged(app, event)
            if(app.EnvironmentParamChanged)
                app.SaveBeforeChange_Environment.Visible = 'on';
                app.SaveBeforeChange_Environment.Enable = 'on';
            else
                updateEnvironmentTable(app); 
                updateSIMPARAM(app);
            end

        end

        % Button pushed function: AddButton_NewRobot
        function AddButton_NewRobotPushed(app, event)
            if(~isempty(app.NewRobotName.Value))
                robotName = app.NewRobotName.Value;
                app.RobotDropDown.Items = [app.RobotDropDown.Items(1:end-1) robotName app.RobotDropDown.Items(end)];
                app.RobotDropDown.ItemsData = [app.RobotDropDown.Items(1:end-1) robotName app.RobotDropDown.Items(end)];
                app.RobotDropDown.Value = robotName;
        
                    % edit fields instead
                    app.TotalmassofROVmEditField.Value = 0;
                    app.VolumeofROVEditField.Value = 0;
                    app.HeightoffrontareaL_hEditField.Value = 0;   
                    app.WidthoffrontareaL_wEditField.Value = 0;
                    app.LengthoffrontareaL_lEditField.Value = 0;
                    app.AroundxaxisI_xEditField.Value = 0;
                    app.AroundyaxisI_yEditField.Value = 0;
                    app.AroundzaxisI_zEditField.Value = 0;

                    app.DistancefromCOtoCOMx_gEditField.Value = 0;
                    app.DistancefromCOtoCOMy_gEditField.Value = 0;
                    app.DistancefromCOtoCOMz_gEditField.Value = 0;
                    app.DistancefromCOtoCOBx_bEditField.Value = 0;
                    app.DistancefromCOtoCOBy_bEditField.Value = 0;
                    app.DistancefromCOtoCOBz_bEditField.Value = 0;

                    app.WheninsurgeX_uEditField.Value = 0;
                    app.WheninswayY_vEditField.Value = 0;
                    app.WheninheaveZ_wEditField.Value = 0;
                    app.WheninrollK_pEditField.Value = 0;
                    app.WheninpitchM_qEditField.Value = 0;
                    app.WheninyawN_rEditField.Value = 0;
                    app.WheninsurgeX_uuEditField.Value = 0;
                    app.WheninswayY_vvEditField.Value = 0;
                    app.WheninheaveZ_wwEditField.Value = 0;
                    app.WheninrollK_ppEditField.Value = 0;
                    app.WheninpitchM_qqEditField.Value = 0;
                    app.WheninyawN_rrEditField.Value = 0;

                    app.FrontperspectiveA_FEditField.Value = 0;
                    app.SideperspectiveA_SEditField.Value = 0;
                    app.TopperspectiveA_TEditField.Value = 0;
                    app.WheninsurgeX_udEditField.Value = 0;
                    app.WheninswayY_vdEditField.Value = 0;
                    app.WheninheaveZ_wdEditField.Value = 0;
                    app.WheninrollK_pdEditField.Value = 0;
                    app.WheninpitchM_qdEditField.Value = 0;
                    app.WheninyawN_rdEditField.Value = 0;

                app.NewRobotPanel.Visible = 'off';
                app.NewRobotPanel.Enable = 'off';
                saveRobotTable(app,robotName)
                updateSIMPARAM(app);
            end
        end

        % Button pushed function: AddButton_NewEnv
        function AddButton_NewEnvPushed(app, event)
            if(~isempty(app.NewEnvironmentName.Value))
                envName = app.NewEnvironmentName.Value;
                app.EnvironmentDropDown.Items = [app.EnvironmentDropDown.Items(1:end-1) envName app.EnvironmentDropDown.Items(end)];
                app.EnvironmentDropDown.ItemsData = [app.EnvironmentDropDown.Items(1:end-1) envName app.EnvironmentDropDown.Items(end)];
                app.EnvironmentDropDown.Value = envName;
%                 uit = app.UITable_Environment;
%                 name = ["Gravitational acceleration";"Water density"];
%                 symbols = ["g";char(961)];
%                 values = zeros(2,1);
%                 units = ["m/s^2";"kg/m^3"];
%                 vars = {'Description';'Symbol';'Value';'Unit'};
%                 tdata = table(name,symbols,values,units);
%                 tdata.Properties.VariableNames = vars;
%                 uit.Data = tdata;
%                 uit.ColumnName = vars;
%                 uit.ColumnEditable = [false true false];
%                 uit.Visible = 'on';

                app.GravitationalaccelerationgEditField.Value = 0;
                app.WaterdensityEditField.Value = 0;

                app.NewEnvironmentPanel.Visible = 'off';
                app.NewEnvironmentPanel.Enable = 'off';
                saveEnvironmentTable(app,envName)
                updateSIMPARAM(app);
            end
        end

        % Callback function
        function UITable_RobotCellEdit(app, event)
            app.RobotParamsChanged = true;
            app.RobotChangedName = app.RobotDropDown.Value;
            app.SaveRobotParametersButton.Enable = 'on';
        end

        % Button pushed function: YesButton_SaveRobot
        function YesButton_SaveRobotPushed(app, event)
            saveRobotTable(app,app.RobotChangedName);
            updateRobotTable(app);
            app.SaveBeforeChange_Robot.Visible = 'off';
            app.SaveBeforeChange_Robot.Enable = 'off';
            updateSIMPARAM(app);
        end

        % Button pushed function: NoButton_SaveRobot
        function NoButton_SaveRobotPushed(app, event)
            updateRobotTable(app);
            app.SaveBeforeChange_Robot.Visible = 'off';
            app.SaveBeforeChange_Robot.Enable = 'off';
        end

        % Callback function
        function UITable_EnvironmentCellEdit(app, event)
            app.EnvironmentParamChanged = true;
            app.EnvironmentChangedName = app.EnvironmentDropDown.Value;
            app.SaveEnvironmentParametersButton.Enable = 'on';
        end

        % Button pushed function: YesButton_SaveEnvironment
        function YesButton_SaveEnvironmentPushed(app, event)
            saveEnvironmentTable(app,app.EnvironmentChangedName);
            updateEnvironmentTable(app);
            app.SaveBeforeChange_Environment.Visible = 'off';
            app.SaveBeforeChange_Environment.Enable = 'off';
            updateSIMPARAM(app);
        end

        % Button pushed function: NoButton_SaveEnvironment
        function NoButton_SaveEnvironmentPushed(app, event)
            updateEnvironmentTable(app);
            app.SaveBeforeChange_Environment.Visible = 'off';
            app.SaveBeforeChange_Environment.Enable = 'off';
        end

        % Button pushed function: SaveInitialConditions
        function SaveInitialConditionsButtonPushed(app, event)
            saveInitTable(app);
            app.SaveInitialConditions.Enable = 'off';
        end

        % Button pushed function: OpenSimulationButton
        function OpenSimulationButtonPushed(app, event)
            %if(app.EnablevisualizationCheckBox.Value == 1)
            load_system('ROV_Simulation.slx');
            open_system('ROV_Simulation','tab')
%             else
%             load_system('ROV_Simulation_no_VR.slx');
%             open_system('ROV_Simulation_no_VR','tab')
%             end
            app.Open_ref.Enable = 'off';
            app.Open_controller.Enable = 'off';
            app.OpenSimulationButton.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Panel_7.Visible = 'on';
            pause(5);
            app.Open_ref.Enable = 'on';
            app.Open_controller.Enable = 'on';
            app.OpenSimulationButton.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Panel_7.Visible = 'off';
        end

        % Button pushed function: Open_ref
        function Open_refButtonPushed(app, event)
%             if(app.EnablevisualizationCheckBox.Value == 1)
            load_system('ROV_Simulation.slx');
            open_system('ROV_Simulation/Reference Generator','tab')
%             else
%             load_system('ROV_Simulation_no_VR.slx');
%             open_system('ROV_Simulation_no_VR/Reference Generator','tab')
%             end
            app.Open_ref.Enable = 'off';
            app.Open_controller.Enable = 'off';
            app.OpenSimulationButton.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Panel_7.Visible = 'on';
            pause(5);
            app.Open_ref.Enable = 'on';
            app.Open_controller.Enable = 'on';
            app.OpenSimulationButton.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Panel_7.Visible = 'off';
        end

        % Button pushed function: Open_controller
        function Open_controllerButtonPushed(app, event)
%             if(app.EnablevisualizationCheckBox.Value == 1)
            load_system('ROV_Simulation.slx');
            open_system('ROV_Simulation/Controllers','tab')
%             else
%             load_system('ROV_Simulation_no_VR.slx');
%             open_system('ROV_Simulation_no_VR/Controllers','tab')
%             end
            app.Open_ref.Enable = 'off';
            app.Open_controller.Enable = 'off';
            app.OpenSimulationButton.Enable = 'off';
            app.LoadingpleasewaitLabel.Visible = 'on';
            app.Panel_7.Visible = 'on';
            pause(5);
            app.Open_ref.Enable = 'on';
            app.Open_controller.Enable = 'on';
            app.OpenSimulationButton.Enable = 'on';
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.Panel_7.Visible = 'off';
        end

        % Button pushed function: PDFButton
        function PDFButtonPushed(app, event)
            open("Documentation\jmse-10-01898.pdf");            
        end

        % Button pushed function: FolderButton
        function FolderButtonPushed(app, event)
            winopen(fileparts(which('ROV_Simulation.slx')));
        end

        % Callback function
        function PushToolClicked(app, event)
            open('ROV_Simulation.slx');
        end

        % Cell edit callback: UITable_InitEta, UITable_InitNu
        function UITable_InitCellEdit(app, event)
            app.SaveInitialConditions.Enable = 'on';
        end

        % Value changed function: UpperlimitEditField
        function UpperlimitEditFieldValueChanged(app, event)
            app.SavethrusterlimitsButton.Enable = 'on';
        end

        % Value changed function: LowerlimitEditField
        function LowerlimitEditFieldValueChanged(app, event)
            app.SavethrusterlimitsButton.Enable = 'on';
        end

        % Button pushed function: SavethrusterlimitsButton
        function SavethrusterlimitsButtonPushed(app, event)
            saveThrusterLimits(app);
            app.SavethrusterlimitsButton.Enable = 'off';
        end

        % Button pushed function: SaveTAMButton
        function SaveTAMButtonPushed(app, event)
            saveTAM(app);
            app.ResetTAMButton.Enable = 'on';
            app.SaveTAMButton.Enable = 'off';
        end

        % Value changed function: NoofthrustersSpinner
        function NoofthrustersSpinnerValueChanged(app, event)
            value = app.NoofthrustersSpinner.Value;
            app.SaveTAMButton.Enable = 'on';
            if value == 1
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'off';app.T3Label.Visible = 'off';app.T4Label.Visible = 'off';app.T5Label.Visible = 'off';app.T6Label.Visible = 'off';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'off'; app.T2pitch.Visible = 'off'; app.T2roll.Visible = 'off'; app.T2heave.Visible = 'off'; app.T2sway.Visible = 'off'; app.T2surge.Visible = 'off';
            app.T3yaw.Visible = 'off'; app.T3pitch.Visible = 'off'; app.T3roll.Visible = 'off'; app.T3heave.Visible = 'off'; app.T3sway.Visible = 'off'; app.T3surge.Visible = 'off';
            app.T4yaw.Visible = 'off'; app.T4pitch.Visible = 'off'; app.T4roll.Visible = 'off'; app.T4heave.Visible = 'off'; app.T4sway.Visible = 'off'; app.T4surge.Visible = 'off';
            app.T5yaw.Visible = 'off'; app.T5pitch.Visible = 'off'; app.T5roll.Visible = 'off'; app.T5heave.Visible = 'off'; app.T5sway.Visible = 'off'; app.T5surge.Visible = 'off';
            app.T6yaw.Visible = 'off'; app.T6pitch.Visible = 'off'; app.T6roll.Visible = 'off'; app.T6heave.Visible = 'off'; app.T6sway.Visible = 'off'; app.T6surge.Visible = 'off';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 2
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'off';app.T4Label.Visible = 'off';app.T5Label.Visible = 'off';app.T6Label.Visible = 'off';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'off'; app.T3pitch.Visible = 'off'; app.T3roll.Visible = 'off'; app.T3heave.Visible = 'off'; app.T3sway.Visible = 'off'; app.T3surge.Visible = 'off';
            app.T4yaw.Visible = 'off'; app.T4pitch.Visible = 'off'; app.T4roll.Visible = 'off'; app.T4heave.Visible = 'off'; app.T4sway.Visible = 'off'; app.T4surge.Visible = 'off';
            app.T5yaw.Visible = 'off'; app.T5pitch.Visible = 'off'; app.T5roll.Visible = 'off'; app.T5heave.Visible = 'off'; app.T5sway.Visible = 'off'; app.T5surge.Visible = 'off';
            app.T6yaw.Visible = 'off'; app.T6pitch.Visible = 'off'; app.T6roll.Visible = 'off'; app.T6heave.Visible = 'off'; app.T6sway.Visible = 'off'; app.T6surge.Visible = 'off';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 3
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'off';app.T5Label.Visible = 'off';app.T6Label.Visible = 'off';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'off'; app.T4pitch.Visible = 'off'; app.T4roll.Visible = 'off'; app.T4heave.Visible = 'off'; app.T4sway.Visible = 'off'; app.T4surge.Visible = 'off';
            app.T5yaw.Visible = 'off'; app.T5pitch.Visible = 'off'; app.T5roll.Visible = 'off'; app.T5heave.Visible = 'off'; app.T5sway.Visible = 'off'; app.T5surge.Visible = 'off';
            app.T6yaw.Visible = 'off'; app.T6pitch.Visible = 'off'; app.T6roll.Visible = 'off'; app.T6heave.Visible = 'off'; app.T6sway.Visible = 'off'; app.T6surge.Visible = 'off';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 4
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'on';app.T5Label.Visible = 'off';app.T6Label.Visible = 'off';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'on'; app.T4pitch.Visible = 'on'; app.T4roll.Visible = 'on'; app.T4heave.Visible = 'on'; app.T4sway.Visible = 'on'; app.T4surge.Visible = 'on';
            app.T5yaw.Visible = 'off'; app.T5pitch.Visible = 'off'; app.T5roll.Visible = 'off'; app.T5heave.Visible = 'off'; app.T5sway.Visible = 'off'; app.T5surge.Visible = 'off';
            app.T6yaw.Visible = 'off'; app.T6pitch.Visible = 'off'; app.T6roll.Visible = 'off'; app.T6heave.Visible = 'off'; app.T6sway.Visible = 'off'; app.T6surge.Visible = 'off';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 5
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'on';app.T5Label.Visible = 'on';app.T6Label.Visible = 'off';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'on'; app.T4pitch.Visible = 'on'; app.T4roll.Visible = 'on'; app.T4heave.Visible = 'on'; app.T4sway.Visible = 'on'; app.T4surge.Visible = 'on';
            app.T5yaw.Visible = 'on'; app.T5pitch.Visible = 'on'; app.T5roll.Visible = 'on'; app.T5heave.Visible = 'on'; app.T5sway.Visible = 'on'; app.T5surge.Visible = 'on';
            app.T6yaw.Visible = 'off'; app.T6pitch.Visible = 'off'; app.T6roll.Visible = 'off'; app.T6heave.Visible = 'off'; app.T6sway.Visible = 'off'; app.T6surge.Visible = 'off';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 6
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'on';app.T5Label.Visible = 'on';app.T6Label.Visible = 'on';app.T7Label.Visible = 'off';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'on'; app.T4pitch.Visible = 'on'; app.T4roll.Visible = 'on'; app.T4heave.Visible = 'on'; app.T4sway.Visible = 'on'; app.T4surge.Visible = 'on';
            app.T5yaw.Visible = 'on'; app.T5pitch.Visible = 'on'; app.T5roll.Visible = 'on'; app.T5heave.Visible = 'on'; app.T5sway.Visible = 'on'; app.T5surge.Visible = 'on';
            app.T6yaw.Visible = 'on'; app.T6pitch.Visible = 'on'; app.T6roll.Visible = 'on'; app.T6heave.Visible = 'on'; app.T6sway.Visible = 'on'; app.T6surge.Visible = 'on';
            app.T7yaw.Visible = 'off'; app.T7pitch.Visible = 'off'; app.T7roll.Visible = 'off'; app.T7heave.Visible = 'off'; app.T7sway.Visible = 'off'; app.T7surge.Visible = 'off';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 1;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 7
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'on';app.T5Label.Visible = 'on';app.T6Label.Visible = 'on';app.T7Label.Visible = 'on';app.T8Label.Visible = 'off';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'on'; app.T4pitch.Visible = 'on'; app.T4roll.Visible = 'on'; app.T4heave.Visible = 'on'; app.T4sway.Visible = 'on'; app.T4surge.Visible = 'on';
            app.T5yaw.Visible = 'on'; app.T5pitch.Visible = 'on'; app.T5roll.Visible = 'on'; app.T5heave.Visible = 'on'; app.T5sway.Visible = 'on'; app.T5surge.Visible = 'on';
            app.T6yaw.Visible = 'on'; app.T6pitch.Visible = 'on'; app.T6roll.Visible = 'on'; app.T6heave.Visible = 'on'; app.T6sway.Visible = 'on'; app.T6surge.Visible = 'on';
            app.T7yaw.Visible = 'on'; app.T7pitch.Visible = 'on'; app.T7roll.Visible = 'on'; app.T7heave.Visible = 'on'; app.T7sway.Visible = 'on'; app.T7surge.Visible = 'on';
            app.T8yaw.Visible = 'off'; app.T8pitch.Visible = 'off'; app.T8roll.Visible = 'off'; app.T8heave.Visible = 'off'; app.T8sway.Visible = 'off'; app.T8surge.Visible = 'off';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 0;
            elseif value == 8
            app.T1Label.Visible = 'on';app.T2Label.Visible = 'on';app.T3Label.Visible = 'on';app.T4Label.Visible = 'on';app.T5Label.Visible = 'on';app.T6Label.Visible = 'on';app.T7Label.Visible = 'on';app.T8Label.Visible = 'on';
            app.T1yaw.Visible = 'on'; app.T1pitch.Visible = 'on'; app.T1roll.Visible = 'on'; app.T1heave.Visible = 'on'; app.T1sway.Visible = 'on'; app.T1surge.Visible = 'on';
            app.T2yaw.Visible = 'on'; app.T2pitch.Visible = 'on'; app.T2roll.Visible = 'on'; app.T2heave.Visible = 'on'; app.T2sway.Visible = 'on'; app.T2surge.Visible = 'on';
            app.T3yaw.Visible = 'on'; app.T3pitch.Visible = 'on'; app.T3roll.Visible = 'on'; app.T3heave.Visible = 'on'; app.T3sway.Visible = 'on'; app.T3surge.Visible = 'on';
            app.T4yaw.Visible = 'on'; app.T4pitch.Visible = 'on'; app.T4roll.Visible = 'on'; app.T4heave.Visible = 'on'; app.T4sway.Visible = 'on'; app.T4surge.Visible = 'on';
            app.T5yaw.Visible = 'on'; app.T5pitch.Visible = 'on'; app.T5roll.Visible = 'on'; app.T5heave.Visible = 'on'; app.T5sway.Visible = 'on'; app.T5surge.Visible = 'on';
            app.T6yaw.Visible = 'on'; app.T6pitch.Visible = 'on'; app.T6roll.Visible = 'on'; app.T6heave.Visible = 'on'; app.T6sway.Visible = 'on'; app.T6surge.Visible = 'on';
            app.T7yaw.Visible = 'on'; app.T7pitch.Visible = 'on'; app.T7roll.Visible = 'on'; app.T7heave.Visible = 'on'; app.T7sway.Visible = 'on'; app.T7surge.Visible = 'on';
            app.T8yaw.Visible = 'on'; app.T8pitch.Visible = 'on'; app.T8roll.Visible = 'on'; app.T8heave.Visible = 'on'; app.T8sway.Visible = 'on'; app.T8surge.Visible = 'on';
            app.BlueROV2CheckBox.Value = 0;
            app.BlueROV2HeavyCheckBox.Value = 1;
            end
        end

        % Value changed function: T1heave, T1pitch, T1roll, T1surge, 
        % ...and 44 other components
        function T1surgeValueChanged(app, event)
            app.SaveTAMButton.Enable = 'on';
            app.ResetTAMButton.Enable = 'on';
        end

        % Button pushed function: ResetTAMButton
        function ResetTAMButtonPushed(app, event)
            T = [
                [0.7071067811865476, 0.7071067811865476, -0.7071067811865475, -0.7071067811865475, 0.0, 0.0, 0.0, 0.0];
                [-0.7071067811865475, 0.7071067811865475, -0.7071067811865476, 0.7071067811865476, 0.0, 0.0, 0.0, 0.0];
                [0, 0, 0, 0, -1, 1, 1, -1];
                [0,0,0,0, 0.218, 0.218, -0.218, -0.218];
                [0,0,0,0, 0.12, -0.12, 0.12, -0.12];
                [-0.18879751057680816, 0.18879751057680816, 0.18879751057680821, -0.18879751057680821, 0.0, 0.0, 0.0, 0.0];
            ];

            T_inv = pinv(round(T,4));
            save("Parameters\TAM.mat","T","T_inv",'-append');
            updateTAM(app);
            app.NoofthrustersSpinner.Value = 8;
            NoofthrustersSpinnerValueChanged(app);
            updateSIMPARAM(app);
            app.ResetTAMButton.Enable = 'off';
        end

        % Value changed function: Diameterd_tetEditField, EastEEditField, 
        % ...and 13 other components
        function NorthNEditFieldValueChanged(app, event)
            value = app.NorthNEditField.Value;
            app.UpdateexternalforcesButton.Enable = 'on';
        end

        % Button pushed function: UpdateexternalforcesButton
        function UpdateexternalforcesButtonPushed(app, event)
            app.UpdateexternalforcesButton.Enable = 'off';
            % checkboxes
            updateSIMPARAM(app);
            saveEXT(app);
        end

        % Value changed function: ManualforcesCheckBox
        function ManualforcesCheckBoxValueChanged(app, event)
            value = app.ManualforcesCheckBox.Value;
            app.UpdateexternalforcesButton.Enable = 'on';
            if value == 1
                app.ManXedit.Enable = 'on';
                app.ManYedit.Enable = 'on';
                app.ManZedit.Enable = 'on';
                app.ManPhiedit.Enable = 'on';
                app.ManThetaedit.Enable = 'on';
                app.ManPsiedit.Enable = 'on';
            else
                app.ManXedit.Enable = 'off';
                app.ManYedit.Enable = 'off';
                app.ManZedit.Enable = 'off';
                app.ManPhiedit.Enable = 'off';
                app.ManThetaedit.Enable = 'off';
                app.ManPsiedit.Enable = 'off';                
            end

        end

        % Value changed function: TetherCheckBox
        function TetherCheckBoxValueChanged(app, event)
            value = app.TetherCheckBox.Value;
            app.UpdateexternalforcesButton.Enable = 'on';
            if value == 1
                app.InternaldampingC_tetEditField.Enable = 'on';
                app.TangentieldragCt_tetEditField.Enable = 'on'; 
                app.LengthL_tetEditField.Enable = 'on'; 
                app.YoungsmodulusE_tetEditField.Enable = 'on';
                app.NormaldragCn_tetEditField.Enable = 'on';
                app.Massm_tetEditField.Enable = 'on';
                app.Diameterd_tetEditField.Enable = 'on';
            else
                app.InternaldampingC_tetEditField.Enable = 'off'; 
                app.TangentieldragCt_tetEditField.Enable = 'off'; 
                app.LengthL_tetEditField.Enable = 'off'; 
                app.YoungsmodulusE_tetEditField.Enable = 'off'; 
                app.NormaldragCn_tetEditField.Enable = 'off'; 
                app.Massm_tetEditField.Enable = 'off';
                app.Diameterd_tetEditField.Enable = 'off';
            end

        end

        % Value changed function: OceancurrentsCheckBox
        function OceancurrentsCheckBoxValueChanged(app, event)
            value = app.OceancurrentsCheckBox.Value;
            app.UpdateexternalforcesButton.Enable = 'on';
            if value == 1
            app.NorthNEditField.Enable = 'on';
            app.EastEEditField.Enable = 'on';
            else
            app.NorthNEditField.Enable = 'off';  
            app.EastEEditField.Enable = 'off'; 
            end
            
            
        end

        % Callback function: BlueROV2HeavyCheckBox, ImageHeavy
        function ImageClicked(app, event)
            ResetTAMButtonPushed(app);
            app.BlueROV2HeavyCheckBox.Value = 1;
            app.BlueROV2CheckBox.Value = 0;
            app.NoofthrustersSpinner.Value = 8;
            ButtonGroupSelectionChanged(app);
        end

        % Callback function: BlueROV2CheckBox, ImageLight
        function Image2Clicked(app, event)
            ResetTAMButtonPushed(app);
            app.BlueROV2HeavyCheckBox.Value = 0;
            app.BlueROV2CheckBox.Value = 1;
            app.NoofthrustersSpinner.Value = 6;
            ButtonGroupSelectionChanged(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            NoofthrustersSpinnerValueChanged(app);            
        end

        % Value changed function: EnablevisualizationCheckBox
        function EnablevisualizationCheckBoxValueChanged(app, event)
            updateSIMPARAM(app);
            % close already open simulation file
            value = app.EnablevisualizationCheckBox.Value;
            if value == 1 
                
            else
                
            end
        end

        % Button pushed function: AddnewsensorButton
        function AddnewsensorButtonPushed(app, event)
            app.NewSensorPanel.Visible = 'on';
            app.AddnewsensorButton.Enable = 'off';
        end

        % Callback function
        function AddButton_NewSensorPushed(app, event)
            if(~isempty(app.NewSensorName.Value))
                senName = app.NewSensorName.Value;
                app.EnvironmentDropDown.Items = [app.EnvironmentDropDown.Items(1:end-1) envName app.EnvironmentDropDown.Items(end)];
                app.EnvironmentDropDown.ItemsData = [app.EnvironmentDropDown.Items(1:end-1) envName app.EnvironmentDropDown.Items(end)];
                app.EnvironmentDropDown.Value = envName;
                uit = app.UITable_sensor;
                name = ["Gravitational acceleration";"Water density"];
                symbols = ["g";char(961)];
                values = zeros(2,1);
                units = ["m/s^2";"kg/m^3"];
                vars = {'Description';'Symbol';'Value';'Unit'};
                tdata = table(name,symbols,values,units);
                tdata.Properties.VariableNames = vars;
                uit.Data = tdata;
                uit.ColumnName = vars;
                uit.ColumnEditable = [false true false];
                uit.Visible = 'on';
                app.NewEnvironmentPanel.Visible = 'off';
                app.NewEnvironmentPanel.Enable = 'off';
                saveEnvironmentTable(app,envName)
            end
            app.NewSensorPanel.Visible = 'off';
            app.AddnewsensorButton.Enable = 'on';
        end

        % Cell edit callback: UITable_sensor
        function UITable_sensorCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % check magnitude of sample rate
            if indices(2) == 3
                if newData <= 0
                    app.SamplingratemustbelargerthanzeroLabel.Visible = 'on';
                else
                    app.SamplingratemustbelargerthanzeroLabel.Visible = 'off';
                    app.UpdatesensorsButton.Enable = 'on';
                end
            else
                app.UpdatesensorsButton.Enable = 'on';
            end
            %if(table2array(newData(1,3)) || table2array(newData(1,3)) < 0)
            %    app.SamplingratemustbelargerthanzeroLabel.Visible = 'on';
            %else
            %    app.SamplingratemustbelargerthanzeroLabel.Visible = 'off';
                % update save button
            %    app.UpdatesensorsButton.Enable = 'on';
           %end
        end

        % Button pushed function: UpdatesensorsButton
        function UpdatesensorsButtonPushed(app, event)
            app.UpdatesensorsButton.Enable = 'off';
            % update simulation parameters
            updateSIMPARAM(app);
            % update sensor parameters
            saveSensorTable(app);
        end

        % Callback function
        function use_DVLValueChanged(app, event)
            value = app.use_DVL.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            if(value == 1 || app.use_Laser.Value == 1)
                app.xLabel.FontColor = '#000000';
                app.yLabel.FontColor = '#000000';
            else
                app.xLabel.FontColor = 'red';
                app.yLabel.FontColor = 'red';
            end

            if(value == 1)
                app.zLabel.FontColor = '#000000';
            else
                app.zLabel.FontColor = 'red';
            end
        end

        % Callback function
        function use_IMUValueChanged(app, event)
            value = app.use_IMU.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            color = colorswitch(app,value);
            app.Label_13.FontColor = color;
            app.ImagepropertiesLabel.FontColor = color;
            app.pLabel.FontColor = color;
            app.qLabel.FontColor = color;
        end

        % Callback function
        function use_LaserValueChanged(app, event)
            value = app.use_Laser.Value;
            if(value == 1 || app.use_DVL.Value == 1)
                app.xLabel.FontColor = '#000000';
                app.yLabel.FontColor = '#000000';
            else
                app.xLabel.FontColor = 'red';
                app.yLabel.FontColor = 'red';
            end
        end

        % Callback function
        function use_MagValueChanged(app, event)
            value = app.use_Mag.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            color = colorswitch(app,value);
            app.Label_14.FontColor = color;
                app.rLabel.FontColor = color;
        end

        % Callback function
        function use_PresValueChanged(app, event)
            value = app.use_Pres.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            color = colorswitch(app,value);
            app.DLabel.FontColor = color;
        end

        % Callback function
        function use_SBLValueChanged(app, event)
            value = app.use_SBL.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            color = colorswitch(app,value);
            app.NLabel.FontColor = color;
            app.ELabel.FontColor = color;
        end

        % Callback function
        function use_SonarValueChanged(app, event)
            value = app.use_Sonar.Value;
            % update save button
            app.UpdatesensorsButton.Enable = 'on';
            fullstateChk(app)
            % update labels
            color = colorswitch(app,value);
            app.uLabel.FontColor = color;
            app.vLabel.FontColor = color;
            app.wLabel.FontColor = color;
        end

        % Callback function
        function SetallstatestoidealCheckBoxValueChanged(app, event)
            value = app.SetallstatestoidealCheckBox.Value;
            if value == 1
                 app.N_StateDropDown.Value      = 0;
                 app.E_StateDropDown.Value      = 0;
                 app.D_StateDropDown.Value      = 0;
                 app.phi_StateDropDown.Value    = 0;
                 app.theta_StateDropDown.Value  = 0;
                 app.psi_StateDropDown.Value    = 0;
                 app.u_StateDropDown.Value      = 0;
                 app.v_StateDropDown.Value      = 0;
                 app.w_StateDropDown.Value      = 0;
                 app.p_StateDropDown.Value      = 0;
                 app.q_StateDropDown.Value      = 0;
                 app.r_StateDropDown.Value      = 0;
            end

            % enable save button
            app.UpdatesensorsButton.Enable = 'on';
            app.IdealstatesButton.Enable = 'off';
        end

        % Value changed function: D_StateDropDown, E_StateDropDown, 
        % ...and 10 other components
        function N_StateDropDownValueChanged(app, event)
            value = app.N_StateDropDown.Value;
             % enable save button
            app.UpdatesensorsButton.Enable = 'on';
            app.IdealstatesButton.Enable = 'on';
        end

        % Button pushed function: IdealstatesButton
        function IdealstatesButtonPushed(app, event)
             app.N_StateDropDown.Value      = 0;
             app.E_StateDropDown.Value      = 0;
             app.D_StateDropDown.Value      = 0;
             app.phi_StateDropDown.Value    = 0;
             app.theta_StateDropDown.Value  = 0;
             app.psi_StateDropDown.Value    = 0;
             app.u_StateDropDown.Value      = 0;
             app.v_StateDropDown.Value      = 0;
             app.w_StateDropDown.Value      = 0;
             app.p_StateDropDown.Value      = 0;
             app.q_StateDropDown.Value      = 0;
             app.r_StateDropDown.Value      = 0;
             app.UpdatesensorsButton.Enable = 'on';
             app.IdealstatesButton.Enable = 'off';
        end

        % Value changed function: AroundxaxisI_xEditField, 
        % ...and 34 other components
        function AroundzaxisI_zEditFieldValueChanged(app, event)
            app.RobotParamsChanged = true;
            app.RobotChangedName = app.RobotDropDown.Value;
            app.SaveRobotParametersButton.Enable = 'on';
        end

        % Value changed function: GravitationalaccelerationgEditField, 
        % ...and 1 other component
        function GravitationalaccelerationgEditFieldValueChanged(app, event)
            app.EnvironmentParamChanged = true;
            app.EnvironmentChangedName = app.EnvironmentDropDown.Value;
            app.SaveEnvironmentParametersButton.Enable = 'on';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.ROVSimulatorUIFigure = uifigure('Visible', 'off');
            app.ROVSimulatorUIFigure.Name = 'ROV Simulator';
            app.ROVSimulatorUIFigure.Icon = 'BlueROV2_Heavy.png';
            app.TabGroup = uitabgroup(app.ROVSimulatorUIFigure);
            app.SimulationOptionsTab = uitab(app.TabGroup);
            app.SimulationOptionsTab.Title = 'Simulation Options';
            app.Panel_8 = uipanel(app.SimulationOptionsTab);
            app.ManagesubsystemsLabel = uilabel(app.Panel_8);
            app.ManagesubsystemsLabel.Text = 'Manage subsystems';
            app.Panel_7 = uipanel(app.SimulationOptionsTab);
            app.Panel_7.Visible = 'off';
            app.UITable_InitNu = uitable(app.SimulationOptionsTab);
            app.UITable_InitNu.ColumnName = {'DOF'; 'Value'; 'Unit'};
            app.UITable_InitNu.RowName = {};
            app.UITable_InitNu.ColumnEditable = [false true false];
            app.UITable_InitNu.CellEditCallback = createCallbackFcn(app, @UITable_InitCellEdit, true);
            app.UITable_InitEta = uitable(app.SimulationOptionsTab);
            app.UITable_InitEta.ColumnName = {'DOF'; 'Value'; 'Unit'};
            app.UITable_InitEta.RowName = {};
            app.UITable_InitEta.ColumnEditable = [false true false];
            app.UITable_InitEta.CellEditCallback = createCallbackFcn(app, @UITable_InitCellEdit, true);
            app.OpenSimulationButton = uibutton(app.SimulationOptionsTab, 'push');
            app.OpenSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenSimulationButtonPushed, true);
            app.OpenSimulationButton.Icon = fullfile(pathToMLAPP, 'Dependencies', 'Figures', 'BlueROV2_Heavy.png');
            app.OpenSimulationButton.Text = {'Open'; 'Simulation'; ''; ''; ''};
            app.SaveInitialConditions = uibutton(app.SimulationOptionsTab, 'push');
            app.SaveInitialConditions.ButtonPushedFcn = createCallbackFcn(app, @SaveInitialConditionsButtonPushed, true);
            app.SaveInitialConditions.Enable = 'off';
            app.SaveInitialConditions.Text = 'Save Initial Conditions';
            app.Open_ref = uibutton(app.SimulationOptionsTab, 'push');
            app.Open_ref.ButtonPushedFcn = createCallbackFcn(app, @Open_refButtonPushed, true);
            app.Open_ref.Text = 'Reference generator';
            app.Open_controller = uibutton(app.SimulationOptionsTab, 'push');
            app.Open_controller.ButtonPushedFcn = createCallbackFcn(app, @Open_controllerButtonPushed, true);
            app.Open_controller.Text = 'Controller';
            app.LoadingpleasewaitLabel = uilabel(app.SimulationOptionsTab);
            app.LoadingpleasewaitLabel.Visible = 'off';
            app.LoadingpleasewaitLabel.Text = 'Loading, please wait...';
            app.Panel_9 = uipanel(app.SimulationOptionsTab);
            app.Panel_9.Visible = 'off';
            app.SimulationpropertiesLabel = uilabel(app.Panel_9);
            app.SimulationpropertiesLabel.Text = 'Simulation properties';
            app.EnablevisualizationCheckBox = uicheckbox(app.Panel_9);
            app.EnablevisualizationCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnablevisualizationCheckBoxValueChanged, true);
            app.EnablevisualizationCheckBox.Text = 'Enable visualization';
            app.WhenchangingthisparameterremembertoopenanewsimulationfileLabel = uilabel(app.Panel_9);
            app.WhenchangingthisparameterremembertoopenanewsimulationfileLabel.Text = {'When changing this parameter, '; 'remember to open a new simulation file'};
            app.old_version = uibutton(app.SimulationOptionsTab, 'push');
            app.old_version.Visible = 'off';
            app.old_version.Text = 'MATLAB version below 2024a, some cababilities of the APP might not work, please update your MATLAB version';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.TabGroup2 = uitabgroup(app.ParametersTab);
            app.DimensionsTab = uitab(app.TabGroup2);
            app.DimensionsTab.Title = 'Dimensions';
            app.kgLabel = uilabel(app.DimensionsTab);
            app.kgLabel.Text = 'kg';
            app.mLabel_7 = uilabel(app.DimensionsTab);
            app.mLabel_7.Text = 'm';
            app.TotalmassofROVmEditFieldLabel = uilabel(app.DimensionsTab);
            app.TotalmassofROVmEditFieldLabel.Text = 'Total mass of ROV (m)';
            app.TotalmassofROVmEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.TotalmassofROVmEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.HeightoffrontareaL_hEditFieldLabel = uilabel(app.DimensionsTab);
            app.HeightoffrontareaL_hEditFieldLabel.Text = 'Height of front area (L_h)';
            app.HeightoffrontareaL_hEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.HeightoffrontareaL_hEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WidthoffrontareaL_wEditFieldLabel = uilabel(app.DimensionsTab);
            app.WidthoffrontareaL_wEditFieldLabel.Text = 'Width of front area (L_w)';
            app.WidthoffrontareaL_wEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.WidthoffrontareaL_wEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.LengthoffrontareaL_lLabel = uilabel(app.DimensionsTab);
            app.LengthoffrontareaL_lLabel.Text = 'Length of front area (L_l)';
            app.LengthoffrontareaL_lEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.LengthoffrontareaL_lEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.VolumeofROVLabel = uilabel(app.DimensionsTab);
            app.VolumeofROVLabel.Text = 'Volume of ROV (∇)';
            app.VolumeofROVEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.VolumeofROVEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.FrontareaLabel = uilabel(app.DimensionsTab);
            app.FrontareaLabel.Text = 'Front area';
            app.kgm2Label = uilabel(app.DimensionsTab);
            app.kgm2Label.Text = 'kg-m^2';
            app.MomentofinertiaLabel = uilabel(app.DimensionsTab);
            app.MomentofinertiaLabel.Text = 'Moment of inertia';
            app.AroundxaxisI_xEditFieldLabel = uilabel(app.DimensionsTab);
            app.AroundxaxisI_xEditFieldLabel.Text = 'Around x-axis (I_x)';
            app.AroundxaxisI_xEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.AroundxaxisI_xEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.AroundyaxisI_yEditFieldLabel = uilabel(app.DimensionsTab);
            app.AroundyaxisI_yEditFieldLabel.Text = 'Around y-axis (I_y)';
            app.AroundyaxisI_yEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.AroundyaxisI_yEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.AroundzaxisI_zEditFieldLabel = uilabel(app.DimensionsTab);
            app.AroundzaxisI_zEditFieldLabel.Text = 'Around z-axis (I_z)';
            app.AroundzaxisI_zEditField = uieditfield(app.DimensionsTab, 'numeric');
            app.AroundzaxisI_zEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.mLabel_8 = uilabel(app.DimensionsTab);
            app.mLabel_8.Text = 'm';
            app.mLabel_9 = uilabel(app.DimensionsTab);
            app.mLabel_9.Text = 'm';
            app.kgm2Label_2 = uilabel(app.DimensionsTab);
            app.kgm2Label_2.Text = 'kg-m^2';
            app.kgm2Label_3 = uilabel(app.DimensionsTab);
            app.kgm2Label_3.Text = 'kg-m^2';
            app.m3Label = uilabel(app.DimensionsTab);
            app.m3Label.Text = 'm^3';
            app.WeightandbuoyancyTab = uitab(app.TabGroup2);
            app.WeightandbuoyancyTab.Title = 'Weight and buoyancy';
            app.mLabel = uilabel(app.WeightandbuoyancyTab);
            app.mLabel.Text = 'm';
            app.CenterofmassLabel = uilabel(app.WeightandbuoyancyTab);
            app.CenterofmassLabel.Text = 'Center of mass';
            app.CenterofbuoancyLabel = uilabel(app.WeightandbuoyancyTab);
            app.CenterofbuoancyLabel.Text = 'Center of buoancy';
            app.DistancefromCOtoCOMx_gEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOMx_gEditFieldLabel.Text = 'Distance from CO to COM (x_g)';
            app.DistancefromCOtoCOMx_gEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOMx_gEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.DistancefromCOtoCOMy_gEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOMy_gEditFieldLabel.Text = 'Distance from CO to COM (y_g)';
            app.DistancefromCOtoCOMy_gEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOMy_gEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.DistancefromCOtoCOMz_gEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOMz_gEditFieldLabel.Text = 'Distance from CO to COM (z_g)';
            app.DistancefromCOtoCOMz_gEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOMz_gEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.DistancefromCOtoCOBx_bEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOBx_bEditFieldLabel.Text = 'Distance from CO to COB (x_b)';
            app.DistancefromCOtoCOBx_bEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOBx_bEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.DistancefromCOtoCOBy_bEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOBy_bEditFieldLabel.Text = 'Distance from CO to COB (y_b)';
            app.DistancefromCOtoCOBy_bEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOBy_bEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.DistancefromCOtoCOBz_bEditFieldLabel = uilabel(app.WeightandbuoyancyTab);
            app.DistancefromCOtoCOBz_bEditFieldLabel.Text = 'Distance from CO to COB (z_b)';
            app.DistancefromCOtoCOBz_bEditField = uieditfield(app.WeightandbuoyancyTab, 'numeric');
            app.DistancefromCOtoCOBz_bEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.mLabel_2 = uilabel(app.WeightandbuoyancyTab);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.WeightandbuoyancyTab);
            app.mLabel_3.Text = 'm';
            app.mLabel_4 = uilabel(app.WeightandbuoyancyTab);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.WeightandbuoyancyTab);
            app.mLabel_5.Text = 'm';
            app.mLabel_6 = uilabel(app.WeightandbuoyancyTab);
            app.mLabel_6.Text = 'm';
            app.COCenterofOriginCOMCenterOfMassCOBCenterOfBuoyancyLabel = uilabel(app.WeightandbuoyancyTab);
            app.COCenterofOriginCOMCenterOfMassCOBCenterOfBuoyancyLabel.Text = {'CO: Center of Origin'; 'COM: Center Of Mass'; 'COB: Center Of Buoyancy'};
            app.DampingTab = uitab(app.TabGroup2);
            app.DampingTab.Title = 'Damping';
            app.NsmLabel = uilabel(app.DampingTab);
            app.NsmLabel.Text = 'Ns/m';
            app.LineardampingLabel = uilabel(app.DampingTab);
            app.LineardampingLabel.Text = 'Linear damping';
            app.WheninsurgeX_uEditFieldLabel = uilabel(app.DampingTab);
            app.WheninsurgeX_uEditFieldLabel.Text = 'When in surge (X_u)';
            app.WheninsurgeX_uEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninsurgeX_uEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninswayY_vEditFieldLabel = uilabel(app.DampingTab);
            app.WheninswayY_vEditFieldLabel.Text = 'When in sway (Y_v)';
            app.WheninswayY_vEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninswayY_vEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninheaveZ_wEditFieldLabel = uilabel(app.DampingTab);
            app.WheninheaveZ_wEditFieldLabel.Text = 'When in heave (Z_w)';
            app.WheninheaveZ_wEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninheaveZ_wEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninrollK_pEditFieldLabel = uilabel(app.DampingTab);
            app.WheninrollK_pEditFieldLabel.Text = 'When in roll (K_p)';
            app.WheninrollK_pEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninrollK_pEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninpitchM_qEditFieldLabel = uilabel(app.DampingTab);
            app.WheninpitchM_qEditFieldLabel.Text = 'When in pitch (M_q)';
            app.WheninpitchM_qEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninpitchM_qEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninyawN_rEditFieldLabel = uilabel(app.DampingTab);
            app.WheninyawN_rEditFieldLabel.Text = 'When in yaw (N_r)';
            app.WheninyawN_rEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninyawN_rEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.QuadraticdampingLabel = uilabel(app.DampingTab);
            app.QuadraticdampingLabel.Text = 'Quadratic damping';
            app.WheninsurgeX_uuEditFieldLabel = uilabel(app.DampingTab);
            app.WheninsurgeX_uuEditFieldLabel.Text = 'When in surge (X_u|u|)';
            app.WheninsurgeX_uuEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninsurgeX_uuEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninswayY_vvEditFieldLabel = uilabel(app.DampingTab);
            app.WheninswayY_vvEditFieldLabel.Text = 'When in sway (Y_v|v|)';
            app.WheninswayY_vvEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninswayY_vvEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninheaveZ_wwEditFieldLabel = uilabel(app.DampingTab);
            app.WheninheaveZ_wwEditFieldLabel.Text = 'When in heave (Z_w|w|)';
            app.WheninheaveZ_wwEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninheaveZ_wwEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninrollK_ppEditFieldLabel = uilabel(app.DampingTab);
            app.WheninrollK_ppEditFieldLabel.Text = 'When in roll (K_p|p|)';
            app.WheninrollK_ppEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninrollK_ppEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninpitchM_qqEditFieldLabel = uilabel(app.DampingTab);
            app.WheninpitchM_qqEditFieldLabel.Text = 'When in pitch (M_q|q|)';
            app.WheninpitchM_qqEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninpitchM_qqEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninyawN_rrEditFieldLabel = uilabel(app.DampingTab);
            app.WheninyawN_rrEditFieldLabel.Text = 'When in yaw (N_r|r|)';
            app.WheninyawN_rrEditField = uieditfield(app.DampingTab, 'numeric');
            app.WheninyawN_rrEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.NsmLabel_2 = uilabel(app.DampingTab);
            app.NsmLabel_2.Text = 'Ns/m';
            app.NsmLabel_3 = uilabel(app.DampingTab);
            app.NsmLabel_3.Text = 'Ns/m';
            app.NsradLabel_7 = uilabel(app.DampingTab);
            app.NsradLabel_7.Text = 'Ns/rad';
            app.NsradLabel_8 = uilabel(app.DampingTab);
            app.NsradLabel_8.Text = 'Ns/rad';
            app.NsradLabel_9 = uilabel(app.DampingTab);
            app.NsradLabel_9.Text = 'Ns/rad';
            app.Ns2m2Label = uilabel(app.DampingTab);
            app.Ns2m2Label.Text = 'Ns^2/m^2';
            app.Ns2m2Label_2 = uilabel(app.DampingTab);
            app.Ns2m2Label_2.Text = 'Ns^2/m^2';
            app.Ns2m2Label_3 = uilabel(app.DampingTab);
            app.Ns2m2Label_3.Text = 'Ns^2/m^2';
            app.Ns2rad2Label = uilabel(app.DampingTab);
            app.Ns2rad2Label.Text = 'Ns^2/rad^2';
            app.Ns2rad2Label_2 = uilabel(app.DampingTab);
            app.Ns2rad2Label_2.Text = 'Ns^2/rad^2';
            app.Ns2rad2Label_3 = uilabel(app.DampingTab);
            app.Ns2rad2Label_3.Text = 'Ns^2/rad^2';
            app.AddedMassTab = uitab(app.TabGroup2);
            app.AddedMassTab.Title = 'Added Mass';
            app.UpdatedAddedMass = uibutton(app.AddedMassTab, 'push');
            app.UpdatedAddedMass.ButtonPushedFcn = createCallbackFcn(app, @UpdatedAddedMassButtonPushed, true);
            app.UpdatedAddedMass.Text = 'Update Added Mass';
            app.ProjectedareaLabel = uilabel(app.AddedMassTab);
            app.ProjectedareaLabel.Text = 'Projected area';
            app.FrontperspectiveA_FEditFieldLabel = uilabel(app.AddedMassTab);
            app.FrontperspectiveA_FEditFieldLabel.Text = 'Front perspective (A_F)';
            app.FrontperspectiveA_FEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.FrontperspectiveA_FEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.SideperspectiveA_SEditFieldLabel = uilabel(app.AddedMassTab);
            app.SideperspectiveA_SEditFieldLabel.Text = 'Side perspective (A_S)';
            app.SideperspectiveA_SEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.SideperspectiveA_SEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.TopperspectiveA_TEditFieldLabel = uilabel(app.AddedMassTab);
            app.TopperspectiveA_TEditFieldLabel.Text = 'Top perspective (A_T)';
            app.TopperspectiveA_TEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.TopperspectiveA_TEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.kgLabel_2 = uilabel(app.AddedMassTab);
            app.kgLabel_2.Text = 'kg';
            app.AddedmassLabel = uilabel(app.AddedMassTab);
            app.AddedmassLabel.Text = 'Added mass';
            app.WheninsurgeX_udEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninsurgeX_udEditFieldLabel.Text = 'When in surge (X_ud)';
            app.WheninsurgeX_udEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninsurgeX_udEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninswayY_vdEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninswayY_vdEditFieldLabel.Text = 'When in sway (Y_vd)';
            app.WheninswayY_vdEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninswayY_vdEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninheaveZ_wdEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninheaveZ_wdEditFieldLabel.Text = 'When in heave (Z_wd)';
            app.WheninheaveZ_wdEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninheaveZ_wdEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninrollK_pdEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninrollK_pdEditFieldLabel.Text = 'When in roll (K_pd)';
            app.WheninrollK_pdEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninrollK_pdEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninpitchM_qdEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninpitchM_qdEditFieldLabel.Text = 'When in pitch (M_qd)';
            app.WheninpitchM_qdEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninpitchM_qdEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.WheninyawN_rdEditFieldLabel = uilabel(app.AddedMassTab);
            app.WheninyawN_rdEditFieldLabel.Text = 'When in yaw (N_rd)';
            app.WheninyawN_rdEditField = uieditfield(app.AddedMassTab, 'numeric');
            app.WheninyawN_rdEditField.ValueChangedFcn = createCallbackFcn(app, @AroundzaxisI_zEditFieldValueChanged, true);
            app.m2Label = uilabel(app.AddedMassTab);
            app.m2Label.Text = 'm^2';
            app.m2Label_2 = uilabel(app.AddedMassTab);
            app.m2Label_2.Text = 'm^2';
            app.m2Label_3 = uilabel(app.AddedMassTab);
            app.m2Label_3.Text = 'm^2';
            app.kgLabel_3 = uilabel(app.AddedMassTab);
            app.kgLabel_3.Text = 'kg';
            app.kgLabel_4 = uilabel(app.AddedMassTab);
            app.kgLabel_4.Text = 'kg';
            app.kgLabel_5 = uilabel(app.AddedMassTab);
            app.kgLabel_5.Text = 'kg';
            app.kgLabel_6 = uilabel(app.AddedMassTab);
            app.kgLabel_6.Text = 'kg';
            app.kgLabel_7 = uilabel(app.AddedMassTab);
            app.kgLabel_7.Text = 'kg';
            app.RobotPanel_2 = uipanel(app.ParametersTab);
            app.RobotDropDown_2Label = uilabel(app.RobotPanel_2);
            app.RobotDropDown_2Label.Text = 'Robot';
            app.RobotDropDown = uidropdown(app.RobotPanel_2);
            app.RobotDropDown.Items = {'BlueROV', 'Add New'};
            app.RobotDropDown.ItemsData = {'BlueROV', 'AddNew'};
            app.RobotDropDown.ValueChangedFcn = createCallbackFcn(app, @RobotDropDownValueChanged, true);
            app.RobotDropDown.Value = 'BlueROV';
            app.RobotDeleteBtn = uibutton(app.RobotPanel_2, 'push');
            app.RobotDeleteBtn.ButtonPushedFcn = createCallbackFcn(app, @RobotDeleteBtnButtonPushed, true);
            app.RobotDeleteBtn.Icon = fullfile(pathToMLAPP, 'Dependencies', 'Figures', 'appbar.delete.png');
            app.RobotDeleteBtn.Text = '';
            app.Panel_10 = uipanel(app.ParametersTab);
            app.GravitationalaccelerationgEditFieldLabel = uilabel(app.Panel_10);
            app.GravitationalaccelerationgEditFieldLabel.Text = 'Gravitational acceleration (g)';
            app.GravitationalaccelerationgEditField = uieditfield(app.Panel_10, 'numeric');
            app.GravitationalaccelerationgEditField.ValueChangedFcn = createCallbackFcn(app, @GravitationalaccelerationgEditFieldValueChanged, true);
            app.GravitationalaccelerationgEditField.Value = 9.82;
            app.WaterdensityEditFieldLabel = uilabel(app.Panel_10);
            app.WaterdensityEditFieldLabel.Text = 'Water density (ρ)';
            app.WaterdensityEditField = uieditfield(app.Panel_10, 'numeric');
            app.WaterdensityEditField.ValueChangedFcn = createCallbackFcn(app, @GravitationalaccelerationgEditFieldValueChanged, true);
            app.WaterdensityEditField.Value = 1000;
            app.ms2Label = uilabel(app.Panel_10);
            app.ms2Label.Text = 'm/s^2';
            app.kgm3Label = uilabel(app.Panel_10);
            app.kgm3Label.Text = 'kg/m^3';
            app.SaveRobotParametersButton = uibutton(app.ParametersTab, 'push');
            app.SaveRobotParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveRobotParametersButtonPushed, true);
            app.SaveRobotParametersButton.Enable = 'off';
            app.SaveRobotParametersButton.Text = 'Save Robot Parameters';
            app.NewRobotPanel = uipanel(app.ParametersTab);
            app.NewRobotPanel.Enable = 'off';
            app.NewRobotPanel.Visible = 'off';
            app.NewRobotName = uieditfield(app.NewRobotPanel, 'text');
            app.AddButton_NewRobot = uibutton(app.NewRobotPanel, 'push');
            app.AddButton_NewRobot.ButtonPushedFcn = createCallbackFcn(app, @AddButton_NewRobotPushed, true);
            app.AddButton_NewRobot.Text = 'Add';
            app.SaveBeforeChange_Robot = uipanel(app.ParametersTab);
            app.SaveBeforeText_Robot = uilabel(app.SaveBeforeChange_Robot);
            app.SaveBeforeText_Robot.Text = {'Would you like to save the changes before changing'; 'robot?'};
            app.YesButton_SaveRobot = uibutton(app.SaveBeforeChange_Robot, 'push');
            app.YesButton_SaveRobot.ButtonPushedFcn = createCallbackFcn(app, @YesButton_SaveRobotPushed, true);
            app.YesButton_SaveRobot.Text = 'Yes';
            app.NoButton_SaveRobot = uibutton(app.SaveBeforeChange_Robot, 'push');
            app.NoButton_SaveRobot.ButtonPushedFcn = createCallbackFcn(app, @NoButton_SaveRobotPushed, true);
            app.NoButton_SaveRobot.Text = 'No';
            app.Delete_Robot = uipanel(app.ParametersTab);
            app.Delete_Robot.Enable = 'off';
            app.Delete_Robot.Visible = 'off';
            app.Text_DeleteRobot = uilabel(app.Delete_Robot);
            app.Text_DeleteRobot.Text = 'Are you sure, you want to delete this robot?';
            app.YesButton_DeleteRobot = uibutton(app.Delete_Robot, 'push');
            app.YesButton_DeleteRobot.ButtonPushedFcn = createCallbackFcn(app, @YesButton_DeleteRobotPushed, true);
            app.YesButton_DeleteRobot.Text = 'Yes';
            app.NoButton_DeleteRobot = uibutton(app.Delete_Robot, 'push');
            app.NoButton_DeleteRobot.ButtonPushedFcn = createCallbackFcn(app, @NoButton_DeleteRobotPushed, true);
            app.NoButton_DeleteRobot.Text = 'No';
            app.SaveEnvironmentParametersButton = uibutton(app.ParametersTab, 'push');
            app.SaveEnvironmentParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEnvironmentParametersButtonPushed, true);
            app.SaveEnvironmentParametersButton.Enable = 'off';
            app.SaveEnvironmentParametersButton.Text = 'Save Environment Parameters';
            app.NewEnvironmentPanel = uipanel(app.ParametersTab);
            app.NewEnvironmentName = uieditfield(app.NewEnvironmentPanel, 'text');
            app.AddButton_NewEnv = uibutton(app.NewEnvironmentPanel, 'push');
            app.AddButton_NewEnv.ButtonPushedFcn = createCallbackFcn(app, @AddButton_NewEnvPushed, true);
            app.AddButton_NewEnv.Text = 'Add';
            app.SaveBeforeChange_Environment = uipanel(app.ParametersTab);
            app.SaveBeforeText_Environment = uilabel(app.SaveBeforeChange_Environment);
            app.SaveBeforeText_Environment.Text = {'Would you like to save the changes before changing'; 'environment?'};
            app.YesButton_SaveEnvironment = uibutton(app.SaveBeforeChange_Environment, 'push');
            app.YesButton_SaveEnvironment.ButtonPushedFcn = createCallbackFcn(app, @YesButton_SaveEnvironmentPushed, true);
            app.YesButton_SaveEnvironment.Text = 'Yes';
            app.NoButton_SaveEnvironment = uibutton(app.SaveBeforeChange_Environment, 'push');
            app.NoButton_SaveEnvironment.ButtonPushedFcn = createCallbackFcn(app, @NoButton_SaveEnvironmentPushed, true);
            app.NoButton_SaveEnvironment.Text = 'No';
            app.Delete_Environment = uipanel(app.ParametersTab);
            app.Text_DeleteEnvironment = uilabel(app.Delete_Environment);
            app.Text_DeleteEnvironment.Text = {'Are you sure, you want to delete this'; 'environment?'};
            app.YesButton_DeleteEnvironment = uibutton(app.Delete_Environment, 'push');
            app.YesButton_DeleteEnvironment.ButtonPushedFcn = createCallbackFcn(app, @YesButton_DeleteEnvironmentPushed, true);
            app.YesButton_DeleteEnvironment.Text = 'Yes';
            app.NoButton_DeleteEnvironment = uibutton(app.Delete_Environment, 'push');
            app.NoButton_DeleteEnvironment.ButtonPushedFcn = createCallbackFcn(app, @NoButton_DeleteEnvironmentPushed, true);
            app.NoButton_DeleteEnvironment.Text = 'No';
            app.EnvironmentPanel = uipanel(app.ParametersTab);
            app.EnvironmentDropDownLabel = uilabel(app.EnvironmentPanel);
            app.EnvironmentDropDownLabel.Text = 'Environment';
            app.EnvironmentDropDown = uidropdown(app.EnvironmentPanel);
            app.EnvironmentDropDown.Items = {'Pool', 'Add New'};
            app.EnvironmentDropDown.ItemsData = {'Pool', 'AddNew'};
            app.EnvironmentDropDown.ValueChangedFcn = createCallbackFcn(app, @EnvironmentDropDownValueChanged, true);
            app.EnvironmentDropDown.Value = 'Pool';
            app.EnvironmentDeleteBtn = uibutton(app.EnvironmentPanel, 'push');
            app.EnvironmentDeleteBtn.ButtonPushedFcn = createCallbackFcn(app, @EnvironmentDeleteBtnButtonPushed, true);
            app.EnvironmentDeleteBtn.Icon = fullfile(pathToMLAPP, 'Dependencies', 'Figures', 'appbar.delete.png');
            app.EnvironmentDeleteBtn.Text = '';
            app.ThrustersTab = uitab(app.TabGroup);
            app.ThrustersTab.Title = 'Thrusters';
            app.ButtonGroup = uibuttongroup(app.ThrustersTab);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ImageHeavy = uiimage(app.ButtonGroup);
            app.ImageHeavy.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.ImageHeavy.ImageSource = fullfile(pathToMLAPP, 'Dependencies', 'Figures', '8-thruster-vectored-frame.png');
            app.BlueROV2CheckBox = uicheckbox(app.ButtonGroup);
            app.BlueROV2CheckBox.ValueChangedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.BlueROV2CheckBox.Text = 'BlueROV2';
            app.BlueROV2HeavyCheckBox = uicheckbox(app.ButtonGroup);
            app.BlueROV2HeavyCheckBox.ValueChangedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.BlueROV2HeavyCheckBox.Text = 'BlueROV2 Heavy';
            app.Panel_6 = uipanel(app.ThrustersTab);
            app.Panel_5 = uipanel(app.ThrustersTab);
            app.SavethrusterlimitsButton = uibutton(app.Panel_5, 'push');
            app.SavethrusterlimitsButton.ButtonPushedFcn = createCallbackFcn(app, @SavethrusterlimitsButtonPushed, true);
            app.SavethrusterlimitsButton.Enable = 'off';
            app.SavethrusterlimitsButton.Text = 'Save thruster limits';
            app.UpperlimitEditFieldLabel = uilabel(app.Panel_5);
            app.UpperlimitEditFieldLabel.Text = 'Upper limit:';
            app.UpperlimitEditField = uieditfield(app.Panel_5, 'numeric');
            app.UpperlimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperlimitEditFieldValueChanged, true);
            app.LowerlimitLabel = uilabel(app.Panel_5);
            app.LowerlimitLabel.Text = 'Lower limit:';
            app.LowerlimitEditField = uieditfield(app.Panel_5, 'numeric');
            app.LowerlimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerlimitEditFieldValueChanged, true);
            app.SaturateamountofforceforeachthrusterLabel = uilabel(app.Panel_5);
            app.SaturateamountofforceforeachthrusterLabel.Text = 'Saturate amount of force for each thruster';
            app.ThrustersaturationNLabel = uilabel(app.Panel_5);
            app.ThrustersaturationNLabel.Text = 'Thruster saturation [N]';
            app.T1surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T1surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T2surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T3surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T4surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T5surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T6surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T7surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8surge = uieditfield(app.ThrustersTab, 'numeric');
            app.T8surge.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T1sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T2sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T3sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T4sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T5sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T6sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T7sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8sway = uieditfield(app.ThrustersTab, 'numeric');
            app.T8sway.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T1heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T2heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T3heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T4heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T5heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T6heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T7heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8heave = uieditfield(app.ThrustersTab, 'numeric');
            app.T8heave.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T1roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T2roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T3roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T4roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T5roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T6roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T7roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8roll = uieditfield(app.ThrustersTab, 'numeric');
            app.T8roll.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T1pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T2pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T3pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T4pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T5pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T6pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T7pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8pitch = uieditfield(app.ThrustersTab, 'numeric');
            app.T8pitch.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T1yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T2yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T2yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T3yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T3yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T4yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T4yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T5yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T5yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T6yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T6yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T7yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T7yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T8yaw = uieditfield(app.ThrustersTab, 'numeric');
            app.T8yaw.ValueChangedFcn = createCallbackFcn(app, @T1surgeValueChanged, true);
            app.T1Label = uilabel(app.ThrustersTab);
            app.T1Label.Text = 'T1';
            app.T2Label = uilabel(app.ThrustersTab);
            app.T2Label.Text = 'T2';
            app.T3Label = uilabel(app.ThrustersTab);
            app.T3Label.Text = 'T3';
            app.T4Label = uilabel(app.ThrustersTab);
            app.T4Label.Text = 'T4';
            app.T5Label = uilabel(app.ThrustersTab);
            app.T5Label.Text = 'T5';
            app.T6Label = uilabel(app.ThrustersTab);
            app.T6Label.Text = 'T6';
            app.T7Label = uilabel(app.ThrustersTab);
            app.T7Label.Text = 'T7';
            app.T8Label = uilabel(app.ThrustersTab);
            app.T8Label.Text = 'T8';
            app.SurgeLabel = uilabel(app.ThrustersTab);
            app.SurgeLabel.Text = 'Surge';
            app.SwayLabel = uilabel(app.ThrustersTab);
            app.SwayLabel.Text = 'Sway';
            app.HeaveLabel = uilabel(app.ThrustersTab);
            app.HeaveLabel.Text = 'Heave';
            app.RollLabel = uilabel(app.ThrustersTab);
            app.RollLabel.Text = 'Roll';
            app.PitchLabel = uilabel(app.ThrustersTab);
            app.PitchLabel.Text = 'Pitch';
            app.YawLabel = uilabel(app.ThrustersTab);
            app.YawLabel.Text = 'Yaw';
            app.SaveTAMButton = uibutton(app.ThrustersTab, 'push');
            app.SaveTAMButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTAMButtonPushed, true);
            app.SaveTAMButton.Enable = 'off';
            app.SaveTAMButton.Text = 'Save TAM';
            app.NoofthrustersLabel = uilabel(app.ThrustersTab);
            app.NoofthrustersLabel.Text = 'No. of thrusters';
            app.NoofthrustersSpinner = uispinner(app.ThrustersTab);
            app.NoofthrustersSpinner.Limits = [2 8];
            app.NoofthrustersSpinner.ValueChangedFcn = createCallbackFcn(app, @NoofthrustersSpinnerValueChanged, true);
            app.NoofthrustersSpinner.Value = 8;
            app.ThrusterallocationmatrixTAMLabel = uilabel(app.ThrustersTab);
            app.ThrusterallocationmatrixTAMLabel.Text = 'Thruster allocation matrix (TAM)';
            app.ResetTAMButton = uibutton(app.ThrustersTab, 'push');
            app.ResetTAMButton.ButtonPushedFcn = createCallbackFcn(app, @ResetTAMButtonPushed, true);
            app.ResetTAMButton.Enable = 'off';
            app.ResetTAMButton.Text = 'Reset TAM';
            app.ImageLight = uiimage(app.ThrustersTab);
            app.ImageLight.ImageClickedFcn = createCallbackFcn(app, @Image2Clicked, true);
            app.ImageLight.ImageSource = fullfile(pathToMLAPP, 'Dependencies', 'Figures', '6-thruster-vectored-frame.png');
            app.ThrusterconfigurationsLabel = uilabel(app.ThrustersTab);
            app.ThrusterconfigurationsLabel.Text = 'Thruster configurations';
            app.ExternalforcesTab = uitab(app.TabGroup);
            app.ExternalforcesTab.Title = 'External forces';
            app.Panel_4 = uipanel(app.ExternalforcesTab);
            app.EastEEditFieldLabel = uilabel(app.Panel_4);
            app.EastEEditFieldLabel.Text = 'East (E)';
            app.EastEEditField = uieditfield(app.Panel_4, 'numeric');
            app.EastEEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.NorthNEditFieldLabel = uilabel(app.Panel_4);
            app.NorthNEditFieldLabel.Text = 'North (N)';
            app.NorthNEditField = uieditfield(app.Panel_4, 'numeric');
            app.NorthNEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_2 = uilabel(app.Panel_4);
            app.EastEEditFieldLabel_2.Text = 'm/s';
            app.EastEEditFieldLabel_3 = uilabel(app.Panel_4);
            app.EastEEditFieldLabel_3.Text = 'm/s';
            app.Panel_2 = uipanel(app.ExternalforcesTab);
            app.EastEEditFieldLabel_12 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_12.Text = 'N';
            app.EastEEditFieldLabel_13 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_13.Text = 'N';
            app.EastEEditFieldLabel_14 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_14.Text = 'N';
            app.EastEEditFieldLabel_15 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_15.Text = 'Nm';
            app.EastEEditFieldLabel_16 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_16.Text = 'Nm';
            app.EastEEditFieldLabel_17 = uilabel(app.Panel_2);
            app.EastEEditFieldLabel_17.Text = 'Nm';
            app.Panel = uipanel(app.ExternalforcesTab);
            app.Massm_tetEditFieldLabel = uilabel(app.Panel);
            app.Massm_tetEditFieldLabel.Text = 'Mass (m_tet)';
            app.Massm_tetEditField = uieditfield(app.Panel, 'numeric');
            app.Massm_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_5 = uilabel(app.Panel);
            app.EastEEditFieldLabel_5.Text = 'kg/m';
            app.EastEEditFieldLabel_6 = uilabel(app.Panel);
            app.EastEEditFieldLabel_6.Text = '';
            app.NormaldragCn_tetEditFieldLabel = uilabel(app.Panel);
            app.NormaldragCn_tetEditFieldLabel.Text = 'Normal drag (Cn_tet)';
            app.NormaldragCn_tetEditField = uieditfield(app.Panel, 'numeric');
            app.NormaldragCn_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_7 = uilabel(app.Panel);
            app.EastEEditFieldLabel_7.Text = 'N/m^2';
            app.YoungsmodulusE_tetEditFieldLabel = uilabel(app.Panel);
            app.YoungsmodulusE_tetEditFieldLabel.Text = 'Youngs modulus (E_tet)';
            app.YoungsmodulusE_tetEditField = uieditfield(app.Panel, 'numeric');
            app.YoungsmodulusE_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_8 = uilabel(app.Panel);
            app.EastEEditFieldLabel_8.Text = 'm';
            app.LengthL_tetEditFieldLabel = uilabel(app.Panel);
            app.LengthL_tetEditFieldLabel.Text = 'Length (L_tet)';
            app.LengthL_tetEditField = uieditfield(app.Panel, 'numeric');
            app.LengthL_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_9 = uilabel(app.Panel);
            app.EastEEditFieldLabel_9.Text = '';
            app.TangentieldragCt_tetEditFieldLabel = uilabel(app.Panel);
            app.TangentieldragCt_tetEditFieldLabel.Text = 'Tangentiel drag (Ct_tet)';
            app.TangentieldragCt_tetEditField = uieditfield(app.Panel, 'numeric');
            app.TangentieldragCt_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_10 = uilabel(app.Panel);
            app.EastEEditFieldLabel_10.Text = 'Ns/m';
            app.InternaldampingC_tetEditFieldLabel = uilabel(app.Panel);
            app.InternaldampingC_tetEditFieldLabel.Text = 'Internal damping (C_tet)';
            app.InternaldampingC_tetEditField = uieditfield(app.Panel, 'numeric');
            app.InternaldampingC_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.EastEEditFieldLabel_11 = uilabel(app.Panel);
            app.EastEEditFieldLabel_11.Text = 'm';
            app.Diameterd_tetEditFieldLabel = uilabel(app.Panel);
            app.Diameterd_tetEditFieldLabel.Text = 'Diameter (d_tet)';
            app.Diameterd_tetEditField = uieditfield(app.Panel, 'numeric');
            app.Diameterd_tetEditField.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.OceancurrentsCheckBox = uicheckbox(app.ExternalforcesTab);
            app.OceancurrentsCheckBox.ValueChangedFcn = createCallbackFcn(app, @OceancurrentsCheckBoxValueChanged, true);
            app.OceancurrentsCheckBox.Text = 'Ocean currents';
            app.TetherCheckBox = uicheckbox(app.ExternalforcesTab);
            app.TetherCheckBox.ValueChangedFcn = createCallbackFcn(app, @TetherCheckBoxValueChanged, true);
            app.TetherCheckBox.Text = 'Tether';
            app.ManualforcesCheckBox = uicheckbox(app.ExternalforcesTab);
            app.ManualforcesCheckBox.ValueChangedFcn = createCallbackFcn(app, @ManualforcesCheckBoxValueChanged, true);
            app.ManualforcesCheckBox.Text = 'Manual forces';
            app.Label = uilabel(app.ExternalforcesTab);
            app.Label.Text = 'External forces can be added / removed from the simulation by using the checkboxes below';
            app.Label_11 = uilabel(app.ExternalforcesTab);
            app.Label_11.Text = 'External forces caused by ocean currents';
            app.XEditFieldLabel = uilabel(app.ExternalforcesTab);
            app.XEditFieldLabel.Text = 'X';
            app.ManXedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManXedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.Label_4 = uilabel(app.ExternalforcesTab);
            app.Label_4.Text = 'K';
            app.ManPhiedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManPhiedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.YEditFieldLabel = uilabel(app.ExternalforcesTab);
            app.YEditFieldLabel.Text = 'Y';
            app.ManYedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManYedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.Label_5 = uilabel(app.ExternalforcesTab);
            app.Label_5.Text = 'M';
            app.ManThetaedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManThetaedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.ZEditFieldLabel = uilabel(app.ExternalforcesTab);
            app.ZEditFieldLabel.Text = 'Z';
            app.ManZedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManZedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.ExternalforcesTab);
            app.Label_3.Text = 'N';
            app.UpdateexternalforcesButton = uibutton(app.ExternalforcesTab, 'push');
            app.UpdateexternalforcesButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateexternalforcesButtonPushed, true);
            app.UpdateexternalforcesButton.Enable = 'off';
            app.UpdateexternalforcesButton.Text = 'Update external forces';
            app.ManPsiedit = uieditfield(app.ExternalforcesTab, 'numeric');
            app.ManPsiedit.ValueChangedFcn = createCallbackFcn(app, @NorthNEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.ExternalforcesTab);
            app.Label_2.Text = 'Change the manual induced external forces causing disturbances in the 6 DOF';
            app.Label_6 = uilabel(app.ExternalforcesTab);
            app.Label_6.Text = 'External forces caused by the tether';
            app.SensorsTab = uitab(app.TabGroup);
            app.SensorsTab.Title = 'Sensors';
            app.State_positions_tab = uipanel(app.SensorsTab);
            app.NLabel_3 = uilabel(app.State_positions_tab);
            app.NLabel_3.Text = 'N';
            app.N_StateDropDown = uidropdown(app.State_positions_tab);
            app.N_StateDropDown.Items = {'Ideal'};
            app.N_StateDropDown.ItemsData = {'Ideal'};
            app.N_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.N_StateDropDown.Value = 'Ideal';
            app.E_StateDropDown = uidropdown(app.State_positions_tab);
            app.E_StateDropDown.Items = {'Ideal'};
            app.E_StateDropDown.ItemsData = {'Ideal'};
            app.E_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.E_StateDropDown.Value = 'Ideal';
            app.NLabel_4 = uilabel(app.State_positions_tab);
            app.NLabel_4.Text = 'E';
            app.D_StateDropDown = uidropdown(app.State_positions_tab);
            app.D_StateDropDown.Items = {'Ideal'};
            app.D_StateDropDown.ItemsData = {'Ideal'};
            app.D_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.D_StateDropDown.Value = 'Ideal';
            app.NLabel_5 = uilabel(app.State_positions_tab);
            app.NLabel_5.Text = 'D';
            app.phi_StateDropDown = uidropdown(app.State_positions_tab);
            app.phi_StateDropDown.Items = {'Ideal'};
            app.phi_StateDropDown.ItemsData = {'Ideal'};
            app.phi_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.phi_StateDropDown.Value = 'Ideal';
            app.NLabel_6 = uilabel(app.State_positions_tab);
            app.NLabel_6.Text = 'Φ';
            app.theta_StateDropDown = uidropdown(app.State_positions_tab);
            app.theta_StateDropDown.Items = {'Ideal'};
            app.theta_StateDropDown.ItemsData = {'Ideal'};
            app.theta_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.theta_StateDropDown.Value = 'Ideal';
            app.NLabel_7 = uilabel(app.State_positions_tab);
            app.NLabel_7.Text = 'θ';
            app.psi_StateDropDown = uidropdown(app.State_positions_tab);
            app.psi_StateDropDown.Items = {'Ideal'};
            app.psi_StateDropDown.ItemsData = {'Ideal'};
            app.psi_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.psi_StateDropDown.Value = 'Ideal';
            app.NLabel_8 = uilabel(app.State_positions_tab);
            app.NLabel_8.Text = 'Ψ';
            app.UpdatesensorsButton = uibutton(app.SensorsTab, 'push');
            app.UpdatesensorsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdatesensorsButtonPushed, true);
            app.UpdatesensorsButton.Enable = 'off';
            app.UpdatesensorsButton.Text = 'Update sensors';
            app.SensorparametersLabel = uilabel(app.SensorsTab);
            app.SensorparametersLabel.Text = 'List of sensors';
            app.UITable_sensor = uitable(app.SensorsTab);
            app.UITable_sensor.ColumnName = {'Sensor'; 'State-measurement'; 'Sampling rate [Hz]'; 'Delay [s]'; 'Variance'; 'Drift'};
            app.UITable_sensor.RowName = {};
            app.UITable_sensor.CellEditCallback = createCallbackFcn(app, @UITable_sensorCellEdit, true);
            app.AddnewsensorButton = uibutton(app.SensorsTab, 'push');
            app.AddnewsensorButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewsensorButtonPushed, true);
            app.AddnewsensorButton.Enable = 'off';
            app.AddnewsensorButton.Visible = 'off';
            app.AddnewsensorButton.Text = 'Add new sensor';
            app.Image = uiimage(app.SensorsTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Dependencies', 'Figures', 'BlueROV1.png');
            app.SensorparametersLabel_3 = uilabel(app.SensorsTab);
            app.SensorparametersLabel_3.Text = 'Positions';
            app.ChosewhichsensortouseforeachpositionmeasurementLabel = uilabel(app.SensorsTab);
            app.ChosewhichsensortouseforeachpositionmeasurementLabel.Text = 'Chose which sensor to use for each position measurement';
            app.State_velocities_tab = uipanel(app.SensorsTab);
            app.NLabel_9 = uilabel(app.State_velocities_tab);
            app.NLabel_9.Text = 'u';
            app.u_StateDropDown = uidropdown(app.State_velocities_tab);
            app.u_StateDropDown.Items = {'Ideal'};
            app.u_StateDropDown.ItemsData = {'Ideal'};
            app.u_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.u_StateDropDown.Value = 'Ideal';
            app.v_StateDropDown = uidropdown(app.State_velocities_tab);
            app.v_StateDropDown.Items = {'Ideal'};
            app.v_StateDropDown.ItemsData = {'Ideal'};
            app.v_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.v_StateDropDown.Value = 'Ideal';
            app.NLabel_10 = uilabel(app.State_velocities_tab);
            app.NLabel_10.Text = 'v';
            app.w_StateDropDown = uidropdown(app.State_velocities_tab);
            app.w_StateDropDown.Items = {'Ideal'};
            app.w_StateDropDown.ItemsData = {'Ideal'};
            app.w_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.w_StateDropDown.Value = 'Ideal';
            app.NLabel_11 = uilabel(app.State_velocities_tab);
            app.NLabel_11.Text = 'w';
            app.p_StateDropDown = uidropdown(app.State_velocities_tab);
            app.p_StateDropDown.Items = {'Ideal'};
            app.p_StateDropDown.ItemsData = {'Ideal'};
            app.p_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.p_StateDropDown.Value = 'Ideal';
            app.NLabel_12 = uilabel(app.State_velocities_tab);
            app.NLabel_12.Text = 'p';
            app.q_StateDropDown = uidropdown(app.State_velocities_tab);
            app.q_StateDropDown.Items = {'Ideal'};
            app.q_StateDropDown.ItemsData = {'Ideal'};
            app.q_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.q_StateDropDown.Value = 'Ideal';
            app.NLabel_13 = uilabel(app.State_velocities_tab);
            app.NLabel_13.Text = 'q';
            app.r_StateDropDown = uidropdown(app.State_velocities_tab);
            app.r_StateDropDown.Items = {'Ideal'};
            app.r_StateDropDown.ItemsData = {'Ideal'};
            app.r_StateDropDown.ValueChangedFcn = createCallbackFcn(app, @N_StateDropDownValueChanged, true);
            app.r_StateDropDown.Value = 'Ideal';
            app.NLabel_14 = uilabel(app.State_velocities_tab);
            app.NLabel_14.Text = 'r';
            app.SensorparametersLabel_4 = uilabel(app.SensorsTab);
            app.SensorparametersLabel_4.Text = 'Velocities';
            app.ChosewhichsensortouseforeachvelocitymeasurementLabel = uilabel(app.SensorsTab);
            app.ChosewhichsensortouseforeachvelocitymeasurementLabel.Text = 'Chose which sensor to use for each velocity measurement';
            app.EditthedifferentsensorsLabel = uilabel(app.SensorsTab);
            app.EditthedifferentsensorsLabel.Text = 'Edit the different sensors';
            app.SamplingratemustbelargerthanzeroLabel = uilabel(app.SensorsTab);
            app.SamplingratemustbelargerthanzeroLabel.Visible = 'off';
            app.SamplingratemustbelargerthanzeroLabel.Text = 'Sampling rate must be larger than zero';
            app.IdealstatesButton = uibutton(app.SensorsTab, 'push');
            app.IdealstatesButton.ButtonPushedFcn = createCallbackFcn(app, @IdealstatesButtonPushed, true);
            app.IdealstatesButton.Text = 'Ideal states';
            app.DocumentationTab = uitab(app.TabGroup);
            app.DocumentationTab.Title = 'Documentation';
            app.CreatedbyFredrikFoghSrensenLabel = uilabel(app.DocumentationTab);
            app.CreatedbyFredrikFoghSrensenLabel.Text = {'Fredrik Fogh Sørensen'; ''; 'Jesper Liniger'; ''; 'Simon Pedersen'; ''; 'Malte Von Benzon'; ''; 'Jerome Jouffroy'; ''; 'Esben Thomsen Uth'};
            app.PDFButton = uibutton(app.DocumentationTab, 'push');
            app.PDFButton.ButtonPushedFcn = createCallbackFcn(app, @PDFButtonPushed, true);
            app.PDFButton.Text = 'PDF';
            app.CreatedbyLabel = uilabel(app.DocumentationTab);
            app.CreatedbyLabel.Text = 'Created by:';
            app.FolderButton = uibutton(app.DocumentationTab, 'push');
            app.FolderButton.ButtonPushedFcn = createCallbackFcn(app, @FolderButtonPushed, true);
            app.FolderButton.Text = 'Folder';
            app.ImagepropertiesLabel = uilabel(app.DocumentationTab);
            app.ImagepropertiesLabel.Text = 'Image properties:';
            app.AfifKusumaMichaelDziedzicPhotoholgicunsplashcomLabel = uilabel(app.DocumentationTab);
            app.AfifKusumaMichaelDziedzicPhotoholgicunsplashcomLabel.Text = 'Afif Kusuma, Michael Dziedzic, Photoholgic, Beatriz Pérez Moya @ unsplash.com';
            app.BlueROV2BlueRoboticscomLabel = uilabel(app.DocumentationTab);
            app.BlueROV2BlueRoboticscomLabel.Text = 'BlueROV2 @ BlueRobotics.com';
            app.CitationLabel = uilabel(app.DocumentationTab);
            app.CitationLabel.Text = 'Citation:';
            app.ToreferencethissoftwareusethefollowingcitationsLabel = uilabel(app.DocumentationTab);
            app.ToreferencethissoftwareusethefollowingcitationsLabel.Text = 'To reference this software, please use one of the following citation methods';
            app.APALabel = uilabel(app.DocumentationTab);
            app.APALabel.Text = '- APA:';
            app.TextArea = uitextarea(app.DocumentationTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'von Benzon, M., Sørensen, F. F., Uth, E. T., Jouffroy, J., Liniger, J., & Pedersen, S. (2022). An Open-Source Benchmark Simulator: Control of a BlueROV2 Underwater Robot. Journal of Marine Science and Engineering, 10(12), [1898]. https://doi.org/10.3390/jmse10121898'};
            app.TextArea_2 = uitextarea(app.DocumentationTab);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'@article{155da9485c674da289f43bea3262772d,'; 'title = "An Open-Source Benchmark Simulator: Control of a BlueROV2 Underwater Robot",'; 'abstract = "This paper presents a simulation model environment for the popular and low-cost remotely operated vehicle (ROV) BlueROV2 implemented in Simulink{\texttrademark} which has been designed and experimentally validated for benchmark control algorithms for underwater vehicles. The BlueROV2 model is based on Fossen{\textquoteright}s equations and includes a kinematic model of the vehicle, the hydrodynamics of vehicle and water interaction, a dynamic model of the thrusters, and, lastly, the gravitational/buoyant forces. The hydrodynamic parameters and thruster model have been validated in a test facility. The benchmark model also includes the ocean current, modeled as constant velocity. The tether connecting the ROV to the top-site facility has been modeled using the lumped mass method and is implemented as a force input to the ROV model. At last, to show the usefulness of the benchmark model, a case study is presented where a BlueROV2 is deployed to inspect an offshore monopile structure. The case study uses a sliding mode controller designed for the BlueROV2. The controller fulfills the design criteria defined for the case study by following the provided trajectory with a low error. It is concluded that the simulator establishes a benchmark for future control schemes for position control and trajectory tracking under the influence of environmental disturbances.",'; 'author = "{von Benzon}, Malte and S{\o}rensen, {Fredrik Fogh} and Uth, {Esben Thomsen} and Jerome Jouffroy and Jesper Liniger and Simon Pedersen",'; 'year = "2022",'; 'month = dec,'; 'day = "5",'; 'doi = "10.3390/jmse10121898",'; 'language = "English",'; 'volume = "10",'; 'journal = "Journal of Marine Science and Engineering",'; 'issn = "2077-1312",'; 'publisher = "MDPI AG",'; 'number = "12",'; '}'};
            app.TextArea_3 = uitextarea(app.DocumentationTab);
            app.TextArea_3.Editable = 'off';
            app.TextArea_3.Value = {'von Benzon, M, Sørensen, FF, Uth, ET, Jouffroy, J, Liniger, J & Pedersen, S 2022, ''An Open-Source Benchmark Simulator: Control of a BlueROV2 Underwater Robot'', Journal of Marine Science and Engineering, bind 10, nr. 12, 1898. https://doi.org/10.3390/jmse10121898'};
            app.HarvardLabel = uilabel(app.DocumentationTab);
            app.HarvardLabel.Text = '- Harvard:';
            app.BIBTEXLabel = uilabel(app.DocumentationTab);
            app.BIBTEXLabel.Text = '- BIBTEX:';
            app.Image2 = uiimage(app.DocumentationTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Dependencies', 'Figures', 'logo.png');
            app.FooterLabel_Left = uilabel(app.ROVSimulatorUIFigure);
            app.FooterLabel_Left.Text = '   Remotely Operated Vehicle Simulator - Version: 1.2';
            app.FooterLabel_Right = uilabel(app.ROVSimulatorUIFigure);
            app.FooterLabel_Right.Text = 'Aalborg University 2024  ';
            % Show the figure after all components are created
            app.ROVSimulatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ROV_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ROVSimulatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ROVSimulatorUIFigure)
        end
    end
end